classdef SpectraViewer_NativeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        FileMenu         matlab.ui.container.Menu
        LoadBSLMenu      matlab.ui.container.Menu
        EditMenu         matlab.ui.container.Menu
        BSLMenu          matlab.ui.container.Menu
        FilterMenu       matlab.ui.container.Menu
        ByPlatformMenu   matlab.ui.container.Menu
        PlotMenu         matlab.ui.container.Menu
        LinesMenu        matlab.ui.container.Menu
        TheoreticalMenu  matlab.ui.container.Menu
        MeasuredMenu     matlab.ui.container.Menu
        AbsorptionMenu   matlab.ui.container.Menu
        EmissionMenu     matlab.ui.container.Menu
        UI_BSLTable      matlab.ui.control.Table
        UI_SpecTable     matlab.ui.control.Table
        BSLmatLabel      matlab.ui.control.Label
        SpectraLabel     matlab.ui.control.Label
        BSL2SpecButton   matlab.ui.control.Button
        Spec2BSLButton   matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        BSL % Basis spectra library file
        hex
        SelectedBSL
        Spec % Loaded entries from BSL
        Spechex
        SelectedSpec
        PlotHandles
    end
    
    methods (Access = private)
        
        function showBasisLibrary(app, filename)
            rgbval = round(rgb(app.BSL.color)*255); % Magic to get hex from colors
            temphex(:,2:7) = reshape(sprintf('%02X',rgbval.'),6,[]).'; temphex(:,1) = '#';
            temphex = cellstr(temphex);
            
            app.UI_BSLTable.Data = app.BSL(:,1:2);
            for i = 1:height(app.BSL)
                addStyle(app.UI_BSLTable, uistyle('FontColor', temphex{i}), 'cell', [i 1]);
            end
            app.BSLmatLabel.Text = filename;
            app.hex = temphex;
        end
        
        function showSpec(app)
            % Show colored spec names and colors on SpecTable            
            app.UI_SpecTable.Data = app.Spec(:,1:2);
            for i = 1:height(app.Spec)
                addStyle(app.UI_SpecTable, uistyle('FontColor', app.Spechex{i}), 'cell', [i 1]);
            end
        end
        
        function plotSpectra(app)
            % Plot spectra currently in app.Spec
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            
            if isempty(app.Spec)
                return
            end
                        
            j = 1;
            
            for i = 1:height(app.Spec)
                if app.TheoreticalMenu.Checked && ~isempty(app.Spec.tspec{i})
                    % Emission
                    if app.EmissionMenu.Checked
                        p(j) = plot(app.UIAxes, app.Spec.tspec{i}(:,1), app.Spec.tspec{i}(:,3), ...
                            "Color", app.Spechex{i});
                        j = j + 1;
                        
                    end
                    % Absorption
                    if app.AbsorptionMenu.Checked
                        p(j) = plot(app.UIAxes, app.Spec.tspec{i}(:,1), app.Spec.tspec{i}(:,2), ...
                            '--', "Color", app.Spechex{i});
                        j = j + 1;
                    end
     
                end
                
                if app.MeasuredMenu.Checked && ~isempty(app.Spec.mspec{i})
                    % Emission
                    if app.EmissionMenu.Checked
                        NormData = app.Spec.mspec{i}(:,2)/max(app.Spec.mspec{i}(:,2)).*100;
                        p(j) = plot(app.UIAxes, app.Spec.mspec{i}(:,1), NormData, ...
                            '-.', "Color", app.Spechex{i});
                        j = j + 1;
                    end
                    % Absorption - currently no measured absorption spectra
                end
            end
            hold(app.UIAxes, 'off');
            app.PlotHandles = p;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Try to load BSL.mat
            if exist('lib/BSL.mat', 'file')
                uialert(app.UIFigure,'Using basis library found at lib/BSL.mat','BSL found',"Icon",'success');
                app.BSL = struct2table(load('lib/BSL.mat').BSL);
                showBasisLibrary(app, 'lib/BSL.mat');
            else
                uialert(fig,'No basis library found at lib/BSL.mat\nPlease load a valid basis spectra library','Warning', "Icon",'warning');
            end
            addStyle(app.UI_BSLTable, uistyle('BackgroundColor', [0.8 0.8 0.8]));
            addStyle(app.UI_SpecTable, uistyle('BackgroundColor', [0.8 0.8 0.8]));
         
        end

        % Button pushed function: BSL2SpecButton
        function BSL2SpecButtonPushed(app, event)
            % Load the currently selected BSL entries
            if isempty(app.SelectedBSL)
                return
            end
            if isempty(app.Spec)
                app.Spec = app.BSL(unique(app.SelectedBSL(:,1)), :);
                app.Spechex = app.hex(unique(app.SelectedBSL(:,1)));
            else % Need to append BSL entries not already part of spec
                selectedBSLIdx = unique(app.SelectedBSL(:,1));
                selectedBSL = app.BSL(selectedBSLIdx, :);
                selectedHex = app.hex(unique(app.SelectedBSL(:,1)));
                [~, newIdx] = setdiff(app.BSL(selectedBSLIdx, 1:2), app.Spec(:, 1:2));
                addSpec = selectedBSL(newIdx, :);
                addHex = selectedHex(newIdx, :);
                app.Spec = [app.Spec; addSpec];
                app.Spechex = [app.Spechex; addHex];
            end
            showSpec(app);
            plotSpectra(app);
        end

        % Button pushed function: Spec2BSLButton
        function Spec2BSLButtonPushed(app, event)
            if isempty(app.SelectedSpec)
                return
            end
            % Logical array of selected spec
            idx = ismember(1:height(app.Spec), unique(app.SelectedSpec(:,1)));
            app.Spec = app.Spec(~idx, :); % Keep all but selected
            app.Spechex = app.Spechex(~idx);
            showSpec(app);
            plotSpectra(app);
        end

        % Cell selection callback: UI_BSLTable
        function UI_BSLTableCellSelection(app, event)
            app.SelectedBSL = event.Indices;            
        end

        % Cell selection callback: UI_SpecTable
        function UI_SpecTableCellSelection(app, event)
            app.SelectedSpec = event.Indices;   
            
            % Make selected lines larger
            idx = ismember(1:height(app.Spec), unique(app.SelectedSpec(:,1)));
            for i = 1:height(app.Spec)
                if idx(i)
                    app.PlotHandles(i).LineWidth = 2;
                else
                    app.PlotHandles(i).LineWidth = 1;
                end
            end
            drawnow
        end

        % Menu selected function: TheoreticalMenu
        function TheoreticalMenuSelected(app, event)
            if app.TheoreticalMenu.Checked
                app.TheoreticalMenu.Checked = false;
            else
                app.TheoreticalMenu.Checked = true;
            end
            plotSpectra(app);
        end

        % Menu selected function: MeasuredMenu
        function MeasuredMenuSelected(app, event)
            if app.MeasuredMenu.Checked
                app.MeasuredMenu.Checked = false;
            else
                app.MeasuredMenu.Checked = true;
            end
            plotSpectra(app);
        end

        % Menu selected function: AbsorptionMenu
        function AbsorptionMenuSelected(app, event)
            if app.AbsorptionMenu.Checked
                app.AbsorptionMenu.Checked = false;
            else
                app.AbsorptionMenu.Checked = true;
            end  
            plotSpectra(app);
        end

        % Menu selected function: EmissionMenu
        function EmissionMenuSelected(app, event)
            if app.EmissionMenu.Checked
                app.EmissionMenu.Checked = false;
            else
                app.EmissionMenu.Checked = true;
            end      
            plotSpectra(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadBSLMenu = uimenu(app.FileMenu);
            app.LoadBSLMenu.Text = 'Load BSL';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.BSLMenu = uimenu(app.UIFigure);
            app.BSLMenu.Text = 'BSL';
            app.FilterMenu = uimenu(app.BSLMenu);
            app.FilterMenu.Text = 'Filter';
            app.ByPlatformMenu = uimenu(app.FilterMenu);
            app.ByPlatformMenu.Text = '...By Platform';
            app.PlotMenu = uimenu(app.UIFigure);
            app.PlotMenu.Text = 'Plot';
            app.LinesMenu = uimenu(app.PlotMenu);
            app.LinesMenu.Text = 'Lines';
            app.TheoreticalMenu = uimenu(app.LinesMenu);
            app.TheoreticalMenu.MenuSelectedFcn = createCallbackFcn(app, @TheoreticalMenuSelected, true);
            app.TheoreticalMenu.Checked = 'on';
            app.TheoreticalMenu.Text = 'Theoretical';
            app.MeasuredMenu = uimenu(app.LinesMenu);
            app.MeasuredMenu.MenuSelectedFcn = createCallbackFcn(app, @MeasuredMenuSelected, true);
            app.MeasuredMenu.Checked = 'on';
            app.MeasuredMenu.Text = 'Measured';
            app.AbsorptionMenu = uimenu(app.LinesMenu);
            app.AbsorptionMenu.MenuSelectedFcn = createCallbackFcn(app, @AbsorptionMenuSelected, true);
            app.AbsorptionMenu.Separator = 'on';
            app.AbsorptionMenu.Text = 'Absorption';
            app.EmissionMenu = uimenu(app.LinesMenu);
            app.EmissionMenu.MenuSelectedFcn = createCallbackFcn(app, @EmissionMenuSelected, true);
            app.EmissionMenu.Checked = 'on';
            app.EmissionMenu.Text = 'Emission';
            app.UI_BSLTable = uitable(app.UIFigure);
            app.UI_BSLTable.ColumnName = {'Dye'; 'Color'};
            app.UI_BSLTable.RowName = {};
            app.UI_BSLTable.CellSelectionCallback = createCallbackFcn(app, @UI_BSLTableCellSelection, true);
            app.UI_SpecTable = uitable(app.UIFigure);
            app.UI_SpecTable.ColumnName = {'Dye'; 'Color'};
            app.UI_SpecTable.RowName = {};
            app.UI_SpecTable.CellSelectionCallback = createCallbackFcn(app, @UI_SpecTableCellSelection, true);
            app.BSLmatLabel = uilabel(app.UIFigure);
            app.BSLmatLabel.Text = 'BSL.mat';
            app.SpectraLabel = uilabel(app.UIFigure);
            app.SpectraLabel.Text = 'Spectra';
            app.BSL2SpecButton = uibutton(app.UIFigure, 'push');
            app.BSL2SpecButton.ButtonPushedFcn = createCallbackFcn(app, @BSL2SpecButtonPushed, true);
            app.BSL2SpecButton.Text = '<';
            app.Spec2BSLButton = uibutton(app.UIFigure, 'push');
            app.Spec2BSLButton.ButtonPushedFcn = createCallbackFcn(app, @Spec2BSLButtonPushed, true);
            app.Spec2BSLButton.Text = '>';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Intensity (a.u.)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectraViewer_NativeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end