classdef hyperviewer2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        DirectoryLabel                matlab.ui.control.Label
        DirectoryListBox              matlab.ui.control.ListBox
        ControlPanel                  matlab.ui.container.Panel
        UnmixAllButton                matlab.ui.control.Button
        CenterPanel                   matlab.ui.container.Panel
        HyperViewer2Label             matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        UIAxes1                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        BSLTable                      matlab.ui.control.Table
        BSLLabel                      matlab.ui.control.Label
        UnmixButton                   matlab.ui.control.Button
        SaveBSLButton                 matlab.ui.control.Button
        SaveBSL2Button                matlab.ui.control.Button
        UIAxes3                       matlab.ui.control.UIAxes
        File                          matlab.ui.container.Menu
        NewMenu                       matlab.ui.container.Menu
        hyperCFMEMenu                 matlab.ui.container.Menu
        MaestroMenu                   matlab.ui.container.Menu
        BioFormatsMenu                matlab.ui.container.Menu
        DavisLabMenu                  matlab.ui.container.Menu
        ENVIMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        ReloadMenu                    matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        AnalysisMenu                  matlab.ui.container.Menu
        AllAnalysesMenu               matlab.ui.container.Menu
        AsTiffStacksMenu              matlab.ui.container.Menu
        NewImageMenu                  matlab.ui.container.Menu
        ExportAsMenu                  matlab.ui.container.Menu
        MovieMenu                     matlab.ui.container.Menu
        NewBasisSpectraMenu           matlab.ui.container.Menu
        TilesMenu                     matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        SpectraViewerMenu             matlab.ui.container.Menu
        SpeedtestCPUMenu              matlab.ui.container.Menu
        SpeedtestGPUMenu              matlab.ui.container.Menu
        HistogramAnalysisMenu         matlab.ui.container.Menu
        ROIMenu                       matlab.ui.container.Menu
        NewROIMenu                    matlab.ui.container.Menu
        AddROIMenu                    matlab.ui.container.Menu
        NewPointMenu                  matlab.ui.container.Menu
        AddPointMenu                  matlab.ui.container.Menu
        AddROItoBSLMenu               matlab.ui.container.Menu
        ClearLastROIMenu              matlab.ui.container.Menu
        AverageExptMenu               matlab.ui.container.Menu
        PreunmixingMenu               matlab.ui.container.Menu
        PostunmixingMenu              matlab.ui.container.Menu
        PhasorDenoisingMenu           matlab.ui.container.Menu
        OptionsMenu                   matlab.ui.container.Menu
        IncludeAllSpectrainCompositeMenu  matlab.ui.container.Menu
        IncludeSaturatedPixelsMenu    matlab.ui.container.Menu
        GaussianBlurMenu              matlab.ui.container.Menu
        ApplyFlatfieldCorrectionMenu  matlab.ui.container.Menu
        GlobalROIMenu                 matlab.ui.container.Menu
        ApplyFiberImageFilterMenu     matlab.ui.container.Menu
        SamplingCorrectionMenu        matlab.ui.container.Menu
        SpectralSmoothingMenu         matlab.ui.container.Menu
        DemoMenu                      matlab.ui.container.Menu
        hyperCFMEtestMenu             matlab.ui.container.Menu
        MaestrotestMenu               matlab.ui.container.Menu
        ENVItestapplesMenu            matlab.ui.container.Menu
        ENVItestbloodstainsMenu       matlab.ui.container.Menu
        OlympustestMenu               matlab.ui.container.Menu
        OIRtiffstestMenu              matlab.ui.container.Menu
        DebugcodeMenu                 matlab.ui.container.Menu
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        % Initialize structs for data/variable organization
        % Experiment variables - these are only changed when a new expt is loaded
        expt = struct('dirname',    [], ... [str] Path to top-level directory containing images or subfolders of images
            'filetype',   [], ... [str] Indicates detected image filetype
            'mode',       'normal',  ... [str] Specify operation mode {'normal', 'reload', 'debug'}
            'debugExamp', 'off',     ... [str] filetype of debug example {'off', 'hyper', 'maestro', 'apples', 'blood', 'oir', 'oir-tiff'}
            'bslFile',    'BSL.mat', ... [str] file name of the basis spectra library
            'loaded',     false, ... [logical] indicates expt has been loaded succesfully
            'loadvec',    []);   ... [dbl] vector of loading percentages per
            
        % Configuration variables for analysis
        cfg = struct('num_imgs',     0, ... [dbl] # of images in the expt
            'num_spec',     0, ... [dbl] # of basis spectra for image analysis
            'poolobj',     [], ... [pool] Parpool obj for CPU-unmixing
            'gpuUnmix',    [], ... [logical] idicates if gpu is available for unmixing
            'gpuDevCount', [], ... [dbl] # of usable gpus detected
            'gpuInfo',     [], ... [cell] info about local gpus returned by 'gpuDevice'
            'gpuIdx',      [], ... [dbl] index of the selected gpu configuration
            'algorithm',   [], ... [str] algorithm type {'gnnls', 'fnnls', 'lsqnonneg', 'fourier', 'chebyshev'}
            'ARCHSTR',     [], ... [str] detected computer architecture
            'roiIdx',       1, ... [dbl] index indicating the roi to apply
            'roiColors',   [], ...
            'fiber_img',   '1mmfiberimage.tif', ... [str] path to fiber core image for hyperCFME mask
            'fiber_mask',  [], ... [logical] logical mask of fiber cores
            'sigma',        4, ... [dbl] width of gaussian blurring filter in pixels
            'gaussfilt',   [], ... [dbl] gaussian filter for image blurring
            'phasor',      [], ... [struct] sub-struct for phasor analysis params
            'compress',    []);... [struct] sub-struct for image compression params
            
        % Keeps track of the gui state parameters used as variables.
        state = struct('img', 1,  ... [dbl] idx of currently selected image (only one at a time)
            'spec',        1,  ... [dbl] idx of currently selected basis spectra in A matrix (may be vector)
            'bsl',         1,  ... [dbl] idx of currently selected spectra in BSL (may be vector)
            'img_list',    [], ... [cell] array of image file names in img listbox
            'spec_list',   [], ... [cell] array of spectra names in matrix listbox
            'bsl_list',    [], ... [cell] array of spectra names in bsl listbox
            'brightness',  1,  ... [dbl] multiplier to brighten image on display
            'blur',        true,  ... [logical] apply spatial gaussian blur to image
            'fiberfilter', false, ... [logical] apply fiber core filter mask to image (hyperCFME only)
            'flatfield',   false, ... [logical] apply flatfield image correction
            'allspec',     false, ... [logical] include all spectra in composite (some are excluded by default)
            'satpix',      false, ... [logical] apply saturated pixel mask to exclude from analysis
            'globalroi',   false, ... [logical] use one roi on all other images
            'compress',    false, ... [logical] compress over-sampled images (hyperCFME only)
            'smoothSpec',  false, ... [logical] apply smoothing-spline spectral denoising (slow!! nor reccomended)
            'errorbars',   false,  ... [logical] show errorbars
            'legend',      false); ... [logical] show legend
            
        % Initialize image structure
        img = struct('info',     [], ... [struct] directory info returend by dir
            'path',          [], ... [str] path to image or image directory
            'label',         [], ... [str] name of image or image directory (displayed above image listbox)
            'isLoaded',      [], ... [logical] indicates if image has been loaded
            'isUnmixed',     [], ... [logical] indicates if image has been analyzed
            'power',         [], ... [dbl] laser power (if applicable)
            'filename',      [], ... [str] image filename, no ext. (set as the string in the image listbox)
            'imagefiles',    [], ... [cell] full image filename(s) with extension
            'cube',          [], ... [dbl] raw hyperspectral data cube
            'disp',          [], ... [dbl] hyperspectral data cube after pre processing
            'imx',           [], ... [dbl] image size in x dir
            'imy',           [], ... [dbl] image size in y dir
            'satpix',        [], ... [logical] saturated pixel mask
            'A',             [], ... [dbl] unmixing matrix
            'x',             [], ... [dbl] unmixed basis image cube
            'x_corr',        [], ... [dbl] unmixed basis image cube with flatfield correction
            'ff_correction', [], ... [dbl] flatfield correction matrix
            'redchisq',      [], ... [dbl] map of reduced chi squared error in unmixing
            'evalTime',      [], ... [dbl] unmixing computation time
            'colorims',      [], ... [struct] pseudocolored composite images for display
            'roi',           [], ... [struct] roi mask and info
            'roiStats',      [], ... [struct] statistics of the image defined by the roi
            'wl',            [], ... [dbl] vector of wavelength that define cube (can be different from basis spectra)
            'phasor',        [], ... [struct] phasor analysis variables
            'filt',          []);... [logical] vector used to filter wavlength channels if needed
            
        % Global GUI Messages
        msg = struct('nogpu',   'No compatible GPU device was found on this machine, NNLS decomposition analysis will be done on the CPU and might take a while.', ...
            'gpuEnabled',      'GPU decomposition enabled', ...
            'cpu_withparpool', 'CPU decomposition enabled with parallel pool cluster.', ...
            'cpu_noparpool',   'Sequential CPU decomposition enabled.' , ...
            'loadingexpt',     'Loading Experiment... Please Wait.', ...
            'loadingcube',     'Loading Image Cube... Please Wait.', ...
            'unmixing',        'Image analysis in progress, please wait.', ...
            'imageNotAnalzed', 'Error: This image has not been analyzed.', ...
            'loadexpt',        'Please load an expiriment first.', ...
            'loadBasis',       'Please add a basis spectra to the A matrix to analyze image.', ...
            'unmixall',        'Unmix all images first.');
        
        
    end
    
    methods (Access = private)
        
        function loadExperiment(app)
            %% loadExperiment - Analyze a directory, detect filetype, and initialize
            % variables for data analysis
            
            % Don't do this.. save local variables.
            % Local variables
            % expt  = app.expt;
            %cfg   = handles.cfg;
            %state = handles.state;
            
            % error message
            ME = [];
            
            % Get experiment directory
            switch app.expt.mode
                case 'normal'
                    % User selects directory of images
                    dirname = uigetdir(cd,'Select the parent folder that contains the set of images');
                    if dirname == 0
                        ME = 'User pressed cancel.';
                        return
                    end
                    
                    % Reset gui parameters
                    app.state.img  = 1;
                    app.state.spec = 1;
                    app.state.bsl  = 1;
                    app.state.flatfield = false;
                    
                case 'reload'
                    
                    % dirname already saved.
                    dirname = app.expt.dirname;
                    
                    % Retain current gui parameters
                    
                case 'debug'
                    switch app.expt.debugExamp
                        case 'hyper'
                            
                            dirname = [cd filesep 'data' filesep 'mivic'];
                            
                        case 'maestro'
                            
                            dirname = [cd filesep 'demo' filesep 'maestro'];
                            
                        case 'apples'
                            
                            dirname = [cd filesep 'demo' filesep 'apples'];
                            
                        case 'blood'
                            
                            dirname = [cd filesep 'data' filesep 'BloodStain_RPI'];
                            
                        case 'oir'
                            
                            dirname = [cd filesep 'data' filesep 'olympustest'];
                            
                        case 'oir-tiff'
                            
                            dirname = [cd filesep 'data' filesep 'OlympusTiffs'];
                            
                        case 'czi'
                            
                            dirname = [cd filesep 'data' filesep 'Zeiss'];
                            
                    end
                    
                    % Reset gui parameters
                    app.state.img  = 1;
                    app.state.spec = 1;
                    app.state.bsl  = 1;
                    app.state.flatfield = false;
            end
            
            % hyperCFME, maestro, and olympus-tiff images are saved as indv. lamda
            % images - must be sorted into sub-direcotries
            
            % Check if hyperCFME image: Must be tiff file with 'mW_' in the name
            hChk = any(size(dir([dirname filesep '**' filesep '*mW_*.tif']), 1));
            
            % Check if maestro image: Must be tiff file with 'ms_' in the name
            mChk = any(size(dir([dirname filesep '**' filesep '*ms_*.tif']), 1));
            
            % Check if olympus-tiff image: Must be tiff file with 'lambda' in the name
            tChk = any(size(dir([dirname filesep '**' filesep '*lambda*.tif']), 1));
            
            
            % ENVI and OIR and CZI files are saved as data cubes, can be in main directory OR
            % sorted into sub directories (but only one level).
            
            % Check if ENVI file: Must be a '.dat' file.
            eChk = any(size(dir([dirname filesep '*.dat']), 1)) || ...
                any(size(dir([dirname filesep '**' filesep '*.dat']), 1));
            
            % Check if olympus image: Must be file with .oir extension
            oChk = any(size(dir([dirname filesep '*.oir']), 1)) || ...
                any(size(dir([dirname filesep '**' filesep '*.oir']), 1));
            
            
            % Check if slice from Scott Davis's lab: tiff stack with 'Slice' in the
            % name
            sChk = any(size(dir([dirname filesep '**' filesep '*Slice*.tif']), 1));
            if sChk == 1
                mChk = 0;
            end
            
            % Check if zeiss image: Must be file with .czi extension
            zChk = any(size(dir([dirname filesep '*.czi']), 1)) || ...
                any(size(dir([dirname filesep '**' filesep '*.czi']), 1));
            
            % hChk = 1;
            total = eChk + hChk + mChk + oChk + tChk + zChk +sChk;
            
            % Set filetype
            
            if total == 0 % No filetypes found
                ME = 'Could not recognize filetype on selected experiment';
                return;
                
            elseif total > 1 % Too many filetypes found
                ME = 'More than one filetype found. Not supported in same experiment';
                return;
                
            elseif hChk
                % Set filetype
                expt.filetype = 'hyper';
                
            elseif eChk
                % Set filetype
                expt.filetype = 'envi';
                
            elseif mChk
                % Set filetype
                expt.filetype = 'maestro';
                
            elseif oChk
                % Set filetype
                expt.filetype = 'oir';
                
            elseif tChk
                % Set filetype
                expt.filetype = 'oir-tiff';
                
            elseif sChk
                % Set filetype
                expt.filetype = 'slice';
                
            elseif zChk
                % Set filetype
                expt.filetype = 'czi';
            end
            
            % Get directory information
            info = dir(dirname);
            
            % Get rid of hidden directories (these begin with a '.')
            info(strncmp({info.name}, '.', 1)) = [];
            
            % Split files and folders
            folds = struct2cell(info);
            files = struct2cell(info);
            
            % Filter out single files and folders
            folds(:, [folds{5, :}] == 0) = [];
            files(:, [files{5, :}] == 1) = [];
            
            % Only need first row
            folds = {folds{1, :}};
            files = {files{1, :}};
            
            % Check for movie folder and remove from list
            movChk = strcmp(folds, 'movies');
            if any(movChk)
                folds(movChk) = [];
            end
            
            % EMK: Changed endsWith to strfind for compatibility
            % Check for txt files and remove them
            txtChk = endsWith(files, '.txt');
            if any(txtChk)
                files(txtChk) = [];
            end
            
            % Check for spreadsheet files and remove them
            xlsChk = endsWith(files, '.xls');
            if any(xlsChk)
                files(xlsChk) = [];
            end
            
            % Check for matlab files and remove them
            matChk = endsWith(files, '.mat');
            if any(matChk)
                files(matChk) = [];
            end
            
            % Sort using natural sort pattern, otherwise default sort is incorrect
            % May have to do this for mFiles in future versions
            folds = natsort(folds);
            
            % Add any images in sub-folders to the image list. Note that for-loops wont
            % run if 'folds' is empty
            switch expt.filetype
                
                case {'envi', 'oir', 'slice'}
                    % Start with files in main dir
                    paths = fullfile(dirname, files);
                    
                    % Get labels for each image
                    temp = strsplit(dirname, filesep);
                    temp = temp(end);
                    labels = cell(size(paths));
                    [labels(1:end)] = deal(temp);
                    
                    % Total number of files to start with
                    t = length(paths);
                    
                    for i = 1:size(folds, 2)
                        
                        % Get files in sub dir
                        if strcmp(expt.filetype, 'envi')
                            
                            files = struct2cell(dir([dirname filesep folds{1, i} filesep '*.dat']));
                            
                        elseif strcmp(expt.filetype, 'oir')
                            
                            files = struct2cell(dir([dirname filesep folds{1, i} filesep '*.oir']));
                            
                        elseif strcmp(expt.filetype, 'czi')
                            
                            files = struct2cell(dir([dirname filesep folds{1, i} filesep '*.czi']));
                            
                        end
                        
                        % Get rid of any directories
                        files(:, [files{5, :}] == 1) = [];
                        
                        % Number of images in subdir
                        n = size(files, 2);
                        
                        % Save path
                        paths((t + 1):(t + n)) = fullfile({files{2,:}}, {files{1,:}});
                        
                        % Save image label
                        [labels{(t + 1):(t + n)}] = deal(folds{1, i});
                        
                        % Update total
                        t = t + n;
                    end
                    
                    
                case {'hyper', 'maestro', 'oir-tiff'}
                    
                    % For these cases, only need the sub dirs
                    paths  = fullfile(dirname, folds);
                    labels = folds;
                    
                case {'czi'}
                    % Only get files in main dir and label them with filenames not
                    % folders - this way save all creates new dirs for each file
                    paths = fullfile(dirname, files);
                    labels = regexprep(files, '.czi', '');
            end
            
            % Number of images found
            n = length(paths);
            
            % Load percentages for display
            expt.loadvec    = linspace(0, 100, n + 1);
            expt.loadvec(1) = []; % Get rid of the leading 0
            
            % Experiment has been initialized
            expt.loaded = true;
            
            % Load BSL with relevant spectra
            handles.lib = loadBSL(handles.lib, 'all', false);
            state.bsl_list = {handles.lib.tags}';
            
            % Load fiber image and create Mask
            if hChk
                handles.cfg = FME_mask(handles.cfg, 'default');
            end
            
            % Set default image names
            filename = repmat({'image_not_loaded'}, [n 1]);
            % % % isLoaded = num2cell(false(n, 1));
            
            % Save to structures
            [img(1:n).filename] = filename{:};
            [img(1:n).path]     = paths{:};
            [img(1:n).label]    = labels{:};
            [img.isLoaded]      = deal(false);
            [img.isUnmixed]     = deal(false);
            
            state.img_list      = filename;
            
            % Get image cubes from each subdirectory
            % for i = 1:handles.num_images
            % Get image cube
            %     [handles, ME] = getImageCube(i, handles);
            %     [handles, ~] = getImageCube(handles.whichImage, handles);
            
            %     if ~isempty(ME)
            %         warning('Ignoring image file %d of %d: %s \n Error: %s \n\n', ...
            %                  i, handles.num_images, handles.imPaths{i}, ME.message);
            %     end
            
            %     % Update user on progress
            %     handles.msgbox.String = sprintf('Loading %d Images ... %1.0f %%', ...
            %         handles.num_images, ...
            %         handles.percent_load(i));
            %     drawnow;
            % end
            
            % Load basis spectra
            switch expt.mode
                case 'normal'
                    % If spectra are loaded already,
                    if cfg.num_spec > 0
                        % Use them
                        [spec, lib] = loadSpectra(handles.lib, 'current');
                    elseif strcmp(expt.filetype,'slice')==1
                        opt = 'slice_WL';
                        if any(size(dir([dirname filesep '**' filesep '*635*.tif']), 1)) ==1
                            opt = 'slice_635';
                        elseif any(size(dir([dirname filesep '**' filesep '*470*.tif']), 1)) ==1
                            opt = 'slice_470';
                        end
                        [spec, lib] = loadSpectra(handles.BSL, opt);
                    else
                        % If not, use default
                        [spec, lib] = loadSpectra(handles.BSL, expt.filetype);
                    end
                    
                case 'debug'
                    % Load BSL
                    [spec, lib] = loadSpectra(handles.BSL, expt.debugExamp);
                    
                case 'reload'
                    % Use current spectra
                    [spec, lib] = loadSpectra(handles.lib, 'current');
                    
            end
            
            % Update mat listbox contents
            state.spec_list = {spec.tags}';
            
            % Save vars
            expt.dirname = dirname;
            cfg.num_imgs = n;
            cfg.num_spec = length([spec.idx]);
            
            % Save back to handles
            handles.state = state;
            handles.cfg   = cfg;
            handles.expt  = expt;
            handles.spec  = spec;
            handles.lib   = lib;
            
            % Pre-load images
            % for i = [1, n]
            %    [img(i), ~] = getImageCube(img(i), expt.filetype);
            %    [img(i), ~] = unmixImage(img(i), handles);
            % end
            
            handles.img = img;
        end
        
        function getImageCube(app)
            %% getImageCube -  Loads the hypersepctra image cube according to the
            % filetype and path provided in 'img'
            
            if app.im.isLoaded
                % Image already loaded
                return;
            end
            
            
            % Initailze error message.
            ME = [];
            
            % try % to load the image cube
            
            switch app.expt.filetype
                case 'hyper' % Load hyperCFME Data
                    
                    % path specifies sub-directory containing images
                    if ~isfolder(im.path)
                        ME = 'HyperCFME data must be a directory';
                        return;
                    end
                    
                    % Get images in the sub directory
                    FileInfo = struct2cell(dir([im.path filesep '*mW_*.tif']));
                    if isempty(FileInfo)
                        ME = 'File not found';
                        im.filename = 'NOT_LOADED';
                        return;
                    end
                    
                    % Only need image names
                    imagefiles = FileInfo(1,:);
                    
                    % Number of lambda channels
                    num_wl = length(imagefiles);
                    
                    % Take the first file to parse
                    file1 = FileInfo{1,1};
                    
                    % Find the laser power in the filename
                    f = strfind(file1,'-');
                    if isempty(f)
                        f = strfind(file1, ' ');
                    end
                    
                    g = strfind(file1,'mW');
                    if isempty(g)
                        g = strfind(file1, 'Mw');
                    end
                    if isempty(g)
                        g = strfind(file1, 'MW');
                    end
                    % Save image file name
                    filenamebase = file1(1:f(end)-1);
                    
                    % Save laser power from filename
                    pwr_str = file1(f(end)+1:g(end)-1);
                    
                    % Assume integer powers for now, figure out non-integer
                    pwr = str2double(pwr_str);
                    
                    % Filename for listbox
                    filename = [filenamebase ' - ' pwr_str ' mW'];
                    
                    % TODO: Test for non-integer powers
                    %     if length(pwr) > 4
                    %         if strcmp(pwr_str, 'point')
                    %             pwr(n) = str2double(pwr_str(6:end))*.1;
                    %         else
                    %             pwr(n) = str2double(pwr_str);
                    %         end
                    %     end
                    
                    % Load first image to get image size
                    I = double(imread([im.path filesep file1]));
                    [imx, imy] = size(I);
                    
                    % Initialize variables
                    im_cube   = zeros(imx, imy, num_wl);
                    L_sat_all = zeros(imx, imy);
                    
                    
                    for i = 1:num_wl
                        % Load Image
                        I = double(imread([im.path filesep imagefiles{i}]));
                        
                        % Normalize Image (10 bit scale)
                        I = I / 1023;
                        
                        % Saturated pixels are assigned 1 in the current image
                        L_sat = (I > 1);
                        
                        % Running total of saturated pixels across all the channels
                        L_sat_all = L_sat_all + L_sat;
                        
                        % Add the image to the hyperspectral image cube
                        im_cube(:,:,i) = I;
                        
                    end
                    
                    % FLIP the image cube sequence and wavelengths to run from blue to red
                    im_cube = flip(im_cube, 3);
                    
                    % Normalize cube by laser power
                    %         im_cube = im_cube / pwr; % TODO: breaks when pwr is NaN
                    
                    % Get rid of hot corner
                    im_cube(347:end, 880:end, :) = 0;
                    
                    % Dilate and invert the saturation map such that saturated pixels are
                    % assigned 0. Make the saturation map binary
                    L_sat_all = (L_sat_all > 0);
                    
                    % Dilate the saturated pixels 4 pixels * 1.1 micron/pixel = 4 microns
                    L_sat_all = bwmorph(L_sat_all,'dilate', 4);
                    
                    % Now L_sat is assigned zeros for any saturated pixels across the image cube
                    SatPixels = (L_sat_all == 0);
                    
                    % No wavelength data embedded in images, hardcode
                    wlvec = [641 652 663 674 685 696 707 718 729 740 751 762 773 784 795 806]';
                    filt = true(size(wlvec));
                    
                case 'maestro'
                    % path specifies sub-directory containing images
                    if ~isfolder(im.path)
                        error('Maestro data must be a directory');
                    end
                    
                    % Get images in the sub directory
                    FileInfo = struct2cell(dir([im.path filesep '*ms*.tif']));
                    
                    % Only need file names
                    imagefiles = FileInfo(1,:);
                    
                    num_wl = length(imagefiles);
                    
                    %         if num_wl ~= length(wlvec)
                    %             image_msg = sprintf(['Number of images: %d. \n'...
                    %                 'Expected number : %d. \n'...
                    %                 'Path: %s \n'], ...
                    %                 length(wlvec), num_wl,...
                    %                 [handles.Paths{n}]);
                    %             fprintf(image_msg);
                    %             error('Code did not find correct number of images to read');
                    %         end
                    %
                    % Take the first file to parse
                    file1 = FileInfo{1,1};
                    
                    % Load first image to get image size
                    I = double(imread([im.path filesep file1]));
                    [imx, imy] = size(I);
                    
                    % Initialize variables
                    im_cube   = zeros(imx, imy, num_wl);
                    %         L_sat_all = zeros(imx, imy);
                    
                    wlvec = zeros(1, num_wl);
                    for i = 1:num_wl
                        % Path to image
                        impath = [im.path filesep imagefiles{i}];
                        
                        % Load Image
                        I = double(imread(impath));
                        
                        % Add the image to the hyperspectral image cube
                        im_cube(:,:,i) = I;
                        
                        % Parse filename
                        [~, filename, ~] = fileparts(impath);
                        parts = strsplit(filename, '_');
                        
                        % Save wavelength
                        wlvec(i) = str2double(parts{end});
                        
                        % TODO: Save exposure
                    end
                    
                    % Save base filename
                    filename = [sprintf('%s_', parts{1:end-2}), parts{end-1}];
                    
                    % TODO: Check for saturated pixels
                    SatPixels = ones(imx, imy);
                    
                    % No pwr to save
                    pwr = [];
                    filt = true(size(wlvec));
                    
                case 'envi' % Load ENVI data
                    % path specifies full path to the .dat file
                    % Requires use of enviread function
                    
                    [filepath, filename, ext] = fileparts(im.path);
                    if ~strcmp(ext, '.dat')
                        ME = 'Path to olympus datfile not specified';
                        return;
                    end
                    
                    hdrfile = [filepath filesep filename '.hdr'];
                    if exist(hdrfile, 'file') ~= 2
                        ME = 'Could not locate hdr file for specified dat file';
                        return;
                    end
                    
                    % Save image files
                    imagefiles = {[filename '.dat'], [filename '.hdr']};
                    
                    % Get image cube
                    [im_cube, info] = enviread(im.path, hdrfile);
                    
                    % For Specim images, rotate 90 degrees
                    if any(strcmp(info.interleave, {'BIL', 'bil'}))
                        im_cube = rot90(im_cube, -1);
                    end
                    
                    % Image parameters
                    [imx, imy, num_wl] = size(im_cube);
                    
                    % Parse sting of wavelengths into vector
                    wlvec = strsplit(info.wavelength, ',');
                    wlvec{1}(1) = []; wlvec{end}(end) = [];
                    wlvec = str2double(wlvec)';
                    if length(wlvec) ~= num_wl
                        % Some warning/error
                    end
                    
                    % For Trutag images, trim wl data from 460 - 700nm
                    if any(strcmp(info.interleave, 'bip'))
                        idx1 = find(wlvec == 460);
                        if isempty(idx1)
                            idx1 = 1;
                        end
                        idx2 = find(wlvec == 700);
                        if isempty(idx2)
                            idx2 = length(wlvec);
                        end
                        
                        wlvec   = wlvec(idx1:idx2);
                        im_cube = im_cube(:, :, idx1:idx2);
                        num_wl  = length(wlvec);
                        
                    end
                    
                    
                    SatPixels = ones(imx, imy);
                    % No pwr to save
                    pwr = [];
                    filt = true(size(wlvec));
                    
                case 'oir'
                    % path specifies full path to oir file
                    % Requires use of the BioFormats Matlab Toolbox:
                    % https://www.openmicroscopy.org/bio-formats/downloads/
                    
                    [~, filename, ext] = fileparts(im.path);
                    if ~strcmp(ext, '.oir')
                        error('Path to olympus datafile not specified');
                    end
                    
                    imagefiles = {filename};
                    
                    % Load data
                    data = bfOpen3DVolume(im.path);
                    metadata = data{1, 2};
                    omeMeta  = data{1,4};
                    xmlString = char(omeMeta.dumpXML());
                    
                    % Save image cube
                    im_cube = data{1, 1}{1, 1};
                    [imx, imy, num_wl] = size(im_cube);
                    
                    % Get wavelength info
                    parts = strsplit(xmlString, '><');
                    
                    % Find correct tag
                    str = parts{startsWith(parts, 'ModuloAlongC')};
                    if isempty(str)
                        error('Could not find wl data in OME metadata');
                    end
                    
                    % Split string again to get info
                    info = strsplit(str, ' ');
                    
                    % Find wl Start
                    startstr = info{startsWith(info, 'Start="')};
                    temp = strsplit(startstr, '"');
                    wlstart = str2double(temp{2});
                    
                    % Find wl End
                    endstr = info{startsWith(info, 'End="')};
                    temp = strsplit(endstr, '"');
                    wlend = str2double(temp{2});
                    
                    % Find wl Step
                    stepstr = info{startsWith(info, 'Step="')};
                    temp = strsplit(stepstr, '"');
                    wlstep = str2double(temp{2});
                    
                    % Save Wavelength
                    wlvec = wlstart:wlstep:wlend;
                    if num_wl ~= length(wlvec)
                        error('OIR Incorrect values found in metatdata');
                    end
                    
                    % Get dye info
                    k = 1;
                    chk = true;
                    spectra = cell(0);
                    while chk == true
                        str = ['dyeData name #' num2str(k)];
                        
                        if ~isempty(metadata.get(str))
                            spectra{k} = metadata.get(str);
                        else
                            chk = false;
                        end
                        k = k + 1;
                    end
                    
                    % Code to get all metadata
                    %         metadataKeys = metadata.keySet().iterator();
                    %         for i=1:metadata.size()
                    %             key = metadataKeys.nextElement();
                    %             value = metadata.get(key);
                    %             fprintf('%s = %s\n', key, value)
                    %         end
                    
                    % No pwr to save
                    pwr = [];
                    filt = true(size(wlvec));
                    SatPixels = ones(imx, imy);
                    
                    % TODO: use this..
                    %         handles.oirSpectra = spectra;
                    
                case 'oir-tiff'
                    
                    % path specifies sub-directory containing images
                    if ~isfolder(im.path)
                        error('OIR-TIFF data must be a directory');
                    end
                    
                    % Get images in the sub directory
                    FileInfo = struct2cell(dir([im.path filesep '*lambda*.tif']));
                    
                    % Only need image names
                    imagefiles = FileInfo(1,:);
                    
                    % Number of lambda channels
                    % TODO: Check for extra files in folder
                    num_wl = length(imagefiles);
                    
                    % Take the first file to parse
                    file1 = FileInfo{1,1};
                    
                    % Get filename info, look for dash
                    parts = strsplit(file1, '-');
                    
                    % Save filename
                    filename = parts{1}(1:end-4);
                    
                    % Wavelength start/end surround the dash
                    wlstart = str2double(parts{1}(end-3:end));
                    wlend   = str2double(parts{2}(1:3)) - 20; % TODO: fix hardcode
                    
                    idx    = strfind(file1, 'nm step size');
                    wlstep = str2double(file1(idx-3:idx-1));
                    
                    wlvec = wlstart:wlstep:wlend;
                    
                    % Load first image to get image size
                    I = double(imread([path filesep file1]));
                    [imx, imy] = size(I);
                    
                    % Initialize variables
                    im_cube = zeros(imx, imy, num_wl);
                    
                    for i = 1:num_wl
                        % Path to image
                        impath = [path filesep imagefiles{i}];
                        
                        % Load Image
                        I = double(imread(impath));
                        
                        % Add the image to the hyperspectral image cube
                        im_cube(:,:,i) = I;
                    end
                    
                    % TODO: Check for saturated pixels
                    SatPixels = ones(imx, imy);
                    
                    % No pwr to save
                    pwr = [];
                    filt = true(size(wlvec));
                    
                case 'slice' % Load Davis Lab data
                    % path specifies full path to the .tif stack
                    
                    [filepath, filename, ext] = fileparts(im.path);
                    if ~strcmp(ext, '.tif')
                        ME = 'Path to .tif stack not specified';
                        return;
                    end
                    
                    % Save image files
                    imagefiles = {[filename '.tif']};
                    
                    % get wavelengths from .mat file in directory and put into vector
                    info = dir(filepath);
                    info(~endsWith({info.name},'.mat')) = [];
                    wlvec = struct2cell(load(fullfile(filepath, info(1).name)));
                    wlvec = wlvec{1};
                    wlvec = table2array(wlvec(:,1));
                    
                    % Image parameters
                    im_cube = imread(im.path,1);
                    [imx, imy] = size(im_cube);
                    num_wl = length(wlvec);
                    
                    % Get image cube
                    im_cube = ones(imx,imy,num_wl);
                    for i = 1:num_wl
                        im_cube(:,:,i) = imread(im.path,i);
                    end
                    
                    SatPixels = ones(imx, imy);
                    % No pwr to save
                    pwr = [];
                    filt = true(size(wlvec));
                    
                case 'czi'
                    % path specifies full path to czi file
                    % Requires use of the BioFormats Matlab Toolbox:
                    % https://www.openmicroscopy.org/bio-formats/downloads/
                    
                    [~, filename, ext] = fileparts(im.path);
                    if ~strcmp(ext, '.czi')
                        error('Path to zeiss datafile not specified');
                    end
                    
                    imagefiles = {filename};
                    
                    % Load data
                    data = bfOpen3DVolume(im.path);
                    metadata = data{1,2};
                    
                    % Save image cube
                    im_cube = data{1, 1}{1, 1};
                    [imx, imy, num_wl] = size(im_cube);
                    
                    % Get wavelength info - direct from channel "Names"
                    allKeys = arrayfun(@char, metadata.keySet.toArray, 'UniformOutput', false);
                    allValues = cellfun(@(x) metadata.get(x), allKeys, 'UniformOutput', false);
                    wlchrcell = allValues(contains(allKeys, 'Global Information|Image|Channel|Name'));
                    wlvec = flip(str2num(cell2mat(wlchrcell)));
                    
                    % Save Wavelength
                    
                    if num_wl ~= length(wlvec)
                        error('CZI Incorrect values found in metatdata');
                    end
                    
                    pwr = [];
                    filt = true(size(wlvec));
                    SatPixels = ones(imx, imy);
                    
            end
            
            % Initialize roi - default roi is entire image
            % im.roi = struct('mask',   ones(imx, imy), ... [dbl] binary mask, 1 inside the roi, 0 outside
            %                 'pts',    [0 0; 0 imx; imy imx; imy 0; 0 0], ... [dbl] (x,y) coords that define the roi boundary
            %                 'type',   'boundary');... [str] specify the type of roi {'boundary', 'point'}
            im.roi = getRegion([imx imy], 'default');
            
            im.roiStats = struct('savg',   [], ... [dbl] average spectrum in roi
                'sstd',   [], ... [dbl] standard dev of savg
                'xavg',   [], ... [dbl] average unmixing coefs in roi
                'xstd',   [], ... [dbl] standard dev of xavg
                'mchisq', [], ... [dbl] average reduced chi squared in roi
                'npx',    []);... [dbl] number of pixels in roi
                
            % OUTPUTS
            im.imx        = imx;
            im.imy        = imy;
            im.power      = pwr;
            im.wl         = wlvec;
            im.filename   = filename;
            im.imagefiles = imagefiles;
            im.cube       = im_cube;
            im.satpix     = SatPixels;
            im.filt       = filt;
            
            % Image is loaded
            im.isLoaded = true;
            im.isUnmixed = false;
            
        end
        
        function cfg = FME_mask(cfg, opt)
            %% FME_mask - Computes a mask to select the fiber-bundle from the raw
            % image, which is useful when calculating the mean intensity of the imaged
            % site (ie, the dark pixels surrounding the fiber would otherwise influence
            % this calculation).
            
            % Load default fiber image or choose a new one
            switch opt
                case 'default'
                    % Use default fiber image
                    
                case 'choose'
                    % Let user chooser fiber image
                    [impath, imdir] = uigetfile('*.tif', 'Choose Fiber image');
                    cfg.fiber_img = [imdir impath];
            end
            
            % Read image
            Ifiber = double(imread(cfg.fiber_img));
            
            % Gaussian convolution filter to remove the fiber cores from the image
            Ifiber = imfilter(Ifiber, cfg.gaussfilt, 'replicate');
            
            % Clear borders
            raw = imclearborder(Ifiber);
            
            % Apply an intensity threshold to select the fiber bundle image
            FME_thresh = 0.05 * max(max(raw));
            
            % Create Mask
            Mask = (raw > FME_thresh);
            Mask = bwmorph(Mask, 'clean');
            Mask = bwmorph(Mask, 'fill');
            Mask = bwareaopen(Mask, 20);
            
            % Erode the fiber edge to avoid calculations that blow up near the edge
            % (may need to adjust this from 1 iter to 4)
            cfg.Mask = bwmorph(Mask, 'erode', 4);
        end
        
        function lib = loadBSL(lib, filetype, editmode)
            %% loadBSL -  Filters the main BSL based on filetype and computes image
            % labels and tags for display
            
            if editmode
                % Let user edit spectra properties
                
                % Define new figure for editing
                f = figure('Position', [100 100 700 550],...
                    'Visible', 'off');
                t = uitable('Parent', f,...
                    'Data', [{lib.name}' {lib.color}' {lib.target}' {lib.compIdx}'],...
                    'ColumnName', {'Name', 'Color','Target', 'Composite Filter Index'},...
                    'RowName', {lib.name},...
                    'Position', [20 80 650 450],...
                    'ColumnEditable', [true true]);
                b = uicontrol('Style', 'pushbutton',...
                    'String', 'Done', ...
                    'Position' , [250 30 100 22], ...
                    'Callback', 'uiresume(gcbf)'); %#ok<NASGU>
                
                % Set to visible after everyting is loaded
                f.Visible = 'on';
                
                % Wait for user to press 'Done'
                uiwait(f);
                
                % Save updated data in table
                dat = t.Data;
                
                % Get rid of figure
                close(f);
                
                % Update library
                [lib(:).name]    = dat{:, 1};
                [lib(:).color]   = dat{:, 2};
                [lib(:).target]  = dat{:, 3};
                [lib(:).compIdx] = dat{:, 4};
                
                % Sort library by filetype, then by name. Source:
                % https://www.mathworks.com/matlabcentral/fileexchange/28573-nestedsortstruct
                %     lib = nestedSortStruct(lib, {'filetype', 'name'});
                % TODO: Add saving feature
                %     % Prompt user to save changes
                %     answer = questdlg('Save changes to BSL permanently?', ...
                %         'Save BSL', 'Yes', 'No', 'No');
                %     switch answer
                %         case 'Yes'
                %             % Save new BSL
                %             save(expt.bslFile, handles.bslFilename(1:end-4));
                %
                %         case 'No'
                %             % Do nothing
                %     end
            end
            
            % Filter by filetype
            if strcmp(filetype, 'all')
                fidx = true(length(lib), 1);
            else
                fidx = strcmp({lib(:).filetype}, filetype);
            end
            
            % Define sub-BSL based on input filetype
            lib = lib(fidx);
            
            % Compile info to make labels
            n       = length(lib);
            names   = {lib.name}';
            colors  = {lib.color}';
            targets = {lib.target}';
            labels  = cell(n, 1);
            tags    = cell(n, 1);
            
            % Set up BSL Listbox and make specra labels and tags
            pre = '<HTML><FONT color="';
            post = '</FONT></HTML>';
            for i = 1:n
                % Find rgb value on [0 - 255] scale
                rgbval = round(rgb(colors{i}) * 255);
                
                % Convert rgb value to hex
                hex = ['#' reshape(sprintf('%02X', rgbval.'),6,[]).'];
                
                % Text for label
                labels{i} = [ names{i} ' - ' colors{i}];
                if ~isempty(targets{i})
                    labels{i} = [labels{i} ' - ' targets{i}];
                end
                
                % Make tags for listbox display
                tags{i} = [pre hex '">' labels{i} post];
            end
            
            % Save variables to library
            [lib.labels] = labels{:};
            [lib.tags]   = tags{:};
        end
        
        function [spec, lib] = loadSpectra(lib, opt)
            %% loadSpectra - Parse basis spectra library in listbox and get info
            
            % Spec struct contains data to construct the unimixing A matrix
            spec = struct('name',  [], ... [str] name of the spectra
                'data',  [], ... [dbl] vector containing the spectral data
                'wl',    [], ... [dbl] vector of wavelengths that define the spectral data
                'color', [], ... [str] name of the color assigned to spectrum
                'label', [], ... [str] spectrum label for legend display
                'tags',  [], ... [str] html tags for display in listbox
                'idx',   [], ... [dbl] idx of the spectra in the lib
                'filt',  []);... [logical] vector used to filter basis spectra channels if needed
                
            % Current A matrix index
            idx = [lib.matidx]';
            
            % Initialize
            newidx = [];
            names  = [];
            
            % Basis spectra names for default loading
            allNames = {lib.name}';
            
            % Get basis spectra index list
            switch opt
                case {'select'}
                    % Let user pick basis spectra
                    newidx = listdlg('ListString', allNames, ...
                        'PromptString', 'Select Basis Spectra for this Experiment', ...
                        'SelectionMode', 'multiple');
                    if isempty(idx)
                        % User pressed cancel
                        return;
                    end
                    
                case 'selectColors'
                    
                    
                case 'current'
                    % Use current spectra in listbox
                    newidx = find(idx);
                    
                case 'fromGUI'
                    
                    % TODO: Fix this
                    newidx = find(idx);
                    % % %         names = handles.specNames(handles.matidx);
                    % Use specIdx values taken from SpectraViewerGUI
                    %         idx  = handles.specIdx;
                    
                case 'hyper'
                    % Default spectra to load if hyperCFME filetype detected
                    names = {'AF633-7-14-20', 'AF660-7-14-20', ...
                        'AF680-7-14-20', 'AF700-v3-7-14-20', ...
                        'Laser10mW-7-14-20'};
                    
                case 'envi'
                    
                    names = [];
                    
                case 'apples'
                    
                    names = {'Real Red', 'Real Green', 'Fake Red', 'Fake Green', ...
                        'WhiteRef_Apple'};
                    
                case 'blood'
                    % Default spectra to for bloodstain test case
                    
                    names = {'BloodStain', 'BlueStain', 'WhiteCloth', 'WhitePaper'};
                    
                case 'maestro'
                    % Default spectra to for maestro data
                    names = {'BPD-TX', 'Bladder465', 'Organ465', 'Skin465',...
                        'Stomach465'};
                    
                case 'oir'
                    % Default spectra to for olympus data
                    % Use spectra found in metadata
                    % TODO: fix thislib
                    %  names = handles.oirSpectra;
                    names = [];
                    if isempty(names)
                        % Use defaults
                        names = {'TSB-C1', 'TSB-C2', 'TSB-C3', 'TSB-C4', ...
                            'TSB-C5', 'OlympusBckgrd'};
                    end
                    
                case 'oir-tiff'
                    names = {'TSB-C1', 'TSB-C2', 'TSB-C3', 'TSB-C4', ...
                        'TSB-C5', 'OlympusBckgrd'};
                    
                case 'slice_WL'
                    names = {'brain_WL', 'OCT_WL', 'Sinus_WL', 'Skin_WL', ...
                        'Stomach_WL', 'Viable_WL', 'Necrosis_WL'};
                    
                case 'slice_470'
                    names = {'FITC_470', 'brain_470', 'OCT_470', 'skin_470', 'sinus_470', ...
                        'body_470', 'tumor_470', 'well_470'};
                    
                case 'slice_635'
                    % only 7 channels in cube so had to remove OCT_635 basis (max 7 bases)
                    names = {'IR680_635', 'brain_635', 'Sinus_635', 'Skin_635', ...
                        'Stomach_635', 'Tumor_635', 'Well_635'};
                    
            end
            
            % If new sort idx was not specifed, use default names to find it
            if isempty(newidx)
                [~, ~, newidx] = intersect(names, allNames, 'stable');
            end
            
            % Update selected spectra
            num_spec = numel(newidx);
            
            % Extract spec data
            for i = 1:num_spec
                if ~isempty(lib(newidx(i)).mspec)
                    % If measured data exists, use it
                    spec(i).wl   = lib(newidx(i)).mspec(:,1);
                    spec(i).data = lib(newidx(i)).mspec(:,2);
                else
                    % Otherwise, use theoretical spectra
                    spec(i).wl   = lib(newidx(i)).tspec(:,1);
                    spec(i).data = lib(newidx(i)).tspec(:,2);
                end
                
                spec(i).name  = lib(newidx(i)).name;
                spec(i).tags  = lib(newidx(i)).tags;
                spec(i).filt  = lib(newidx(i)).compIdx;
                spec(i).color = lib(newidx(i)).color;
                spec(i).label = lib(newidx(i)).labels;
                spec(i).idx   = newidx(i);
            end
            
            % Update matidx in library
            [lib.matidx]         = deal(false);
            [lib(newidx).matidx] = deal(true);
        end
        
        function [im, ME] = writeImageCube(im, filetype, savedir)
            
            % Initailze error message.
            ME = [];
            
            cube = im.cube;
            num_wl = size(cube, 3);
            
            switch filetype
                case 'hyper' % Save hyperCFME Data
                    
                    a = 0; b = 0;
                    % Loops backwards because hyperCFME image are saved in reverse
                    % order
                    for i = num_wl:-1:1
                        
                        filename = [im.label '-' num2str(im.power) 'mW_sys' ...
                            num2str(b) '_ch' num2str(a) '.tif'];
                        
                        image = uint16(cube(:, :, i) * 2^16);
                        
                        % Write
                        imwrite(image, [savedir filename]);
                        
                        a = a + 1;
                        if a == 4
                            a = 0;
                            b = b + 1;
                        end
                    end
                    
                    
            end
        end
        
        
        function c_cube = smoothSpectra(wlvec, cube, specrange)
            %% smoothSpectra - use a smoothing spline to smooth spectrum of pixels
            
            [imx, imy, ~] = size(cube);
            c_cube = zeros(size(cube));
            wlvec = wlvec(:);
            
            loadvec = linspace(0, 100, imx + 1);
            loadvec(1) = [];
            % handles.msgbox.String = sprintf('Calculating Splines ...  0%%');
            drawnow;
            
            % Default 4th order peicewise spline poly
            X = [wlvec wlvec.^2 wlvec.^3 wlvec.^4];
            
            % Per pixel smoothing spline fit
            % NOTE: Curve fitting toolbox required for fit
            for i = 1:imx
                parfor j = 1:imy
                    
                    % Get spectrum
                    spec = squeeze(cube(i, j, :));
                    
                    % Fit
                    %         [f,gof,out] = fit(wlvec, spec, 'smoothingspline');
                    %         f = fit(wlvec, spec, 'smoothingspline');
                    options = fitoptions('Method','Smooth','SmoothingParam',0.007);
                    [f,~,~] = fit(wlvec, spec, 'smoothingspline', options);
                    %         out.p
                    % Extract fit
                    coefs = [f.p.coefs; f.p.coefs(end, :)];
                    Y = sum(X.*coefs, 2);
                    % Normalize to scale of spectra
                    Y = Y .* max(spec) ./ max(Y);
                    
                    % Save fit into new cube
                    c_cube(i, j, :) = Y;
                    
                end
                
                %     handles.msgbox.String = sprintf('Calculating Splines ...  %1.0f %%', ...
                %                                     loadvec(i));
                %     drawnow;
                
            end
        end
        
        function c_cube = sampling_correction(cube, p)
            %% sampling_correction -
            % Compresss an image by averaging px pixels in the x and py pixels in the
            % y dimensions for z replicates
            
            isLogic = islogical(cube);
            cube    = double(cube);
            [imx, imy, imz] = size(cube);
            
            % Compressed image dimensions
            imx = floor(imx / p.x);
            imy = floor(imy / p.y);
            c_cube = zeros(imx, imy, imz);
            
            
            for i = 1:imz
                temp = conv2(cube(:,:,i), ones(p.x, p.y), 'valid');
                c_cube(:,:,i) = temp(1:p.x:end, 1:p.y:end) / p.x*p.y;
            end
            
            % Reset to logical
            if isLogic
                c_cube = logical(c_cube);
            end
            
        end
        
        function [colorIms, ME] = colorImage(im, rgbvec, normopt)
            %% colorImage - convert intensity image into (imx x imy x 3) rgb images
            % im is an image with k channels, colors is a (k x 3) matrix of rgb
            % triplets specifying the color of the k'th image.
            [imx, imy, k] = size(im);
            
            if size(rgbvec, 1) ~= k
                ME = 'Color map does not match image size.';
                return
            end
            
            % Initialize
            colorIms = struct('im',    [], ... [dbl] (imx x imy x 3) rgb image
                'rgb',   []);... [dbl] rgb trplet of colorname
                
            % Convert color names to rgb triplets
            rgbvec = reshape(rgbvec, [1 k 3]);
            
            % Option to normalize image
            if normopt
                % Normalize from 0 to 1
                im = mat2gray(im);
                % normIm = im / max(max(max(im)));
            end
            
            % Make pseudo-color image
            for i = 1:k
                
                % Multiply the rgb vector map by the image to pseudocolor
                colorIms(i).im = repmat(rgbvec(1, i, :), [imx imy]) .* ...
                    repmat(im(:, :, i), [1 1 3]);
                
                colorIms(i).rgb = reshape(rgbvec(1, i, :), [1 3]);
                
            end
            % Options for normalization
            % colorImTotal = colorImTotal / k;
            % colorImTotal = mat2gray(colorImTotal);
            % colorImTotal = colorImTotal /  max(max(max(colorImTotal)));
        end
        
        function A = getAmatrix(spec, imagewl, filetype)
            %% getAmatrix - parses the basis spectra to match the image spectral
            % resolution (if required) and retuns normalized A matrix
            
            num_wl   = length(imagewl);
            num_spec = length(spec);
            
            switch filetype
                case {'hyper', 'maestro', 'slice'}
                    
                    % Basis spectra already at correct resolution
                    A = [spec.data];
                    
                case {'envi', 'oir', 'oir-tiff', 'czi'}
                    
                    % Interpolate basis spec data to match image wl res
                    A = zeros(num_wl, num_spec);
                    for i = 1:num_spec
                        
                        % Interp the basis spectra at the image wavelength resolution
                        newspec = interp1(spec(i).wl, spec(i).data, imagewl, 'pchip', 'extrap');
                        
                        % Check if interpolation worked
                        if any(isinf(newspec)) || any(isnan(newspec))
                            % If not, warn user, A matrix kept at zero
                            warning('Ignoring spectra #%d; incorrect interpolation', i);
                            
                        else
                            % All good
                            A(:, i) = newspec;
                        end
                    end
            end
            
            % Normalize
            maxA = repmat(max(A), [size(A, 1), 1]);
            A    = A ./ maxA;
        end
        
        function [im, ME] = unmixImage(im, handles)
            %% unmixImage - do all the image analysis
            
            % Local variables
            state    = handles.state;
            cfg      = handles.cfg;
            spec     = handles.spec;
            cube     = im.cube;
            num_wl   = size(cube, 3);
            num_spec = cfg.num_spec;
            filetype = handles.expt.filetype;
            ME = [];
            
            %%%%% Pre analysis
            % Do these things to the image *before* unmixing
            
            % Over-sampling correction
            if state.compress
                cube   = sampling_correction(cube,      cfg.compress);
                satpix = sampling_correction(im.satpix, cfg.compress);
                
                % Update image size
                [im.imx, im.imy, ~] = size(cube);
                
                % This changes the image size so reset the roi
                im.roi = getRegion(size(cube), 'fibercore');
            end
            
            % Blur
            if state.blur
                for i = 1:num_wl
                    cube(:, :, i) = imfilter(cube(:, :, i), cfg.gaussfilt, 'replicate');
                end
            end
            
            % Fiber filter
            if state.fiberfilter
                cube = cube .* repmat(cfg.fiber_mask, [1 1 num_wl]);
            end
            
            % Flatfield correction
            if state.flatfield
                if isempty(cfg.ff_correction)
                    cfg.ff_correction = ffcorrect(im, cfg);
                end
                cube = cube .* cfg.ffcorrection;
            end
            
            % Smoothing
            if state.smoothSpec
                % Specify range of spectra to fit
                % TODO: move 'specrange' to default setting
                specrange = 1:num_wl;
                cube = smoothSpectra(im.wl, cube, specrange);
            end
            
            % Saturated pixels
            if state.satpix
                cube = cube .* repmat(satpix, [1 1 num_wl]);
            end
            
            cube = mat2gray(cube);
            
            % Pseudocolor the spectral image (normalize)
            % colors = squeeze(spectrumRGB(im.wl));
            
            % Pseudocolor by custom color map
            colors = jet(length(im.wl));
            im.colorims.cube = colorImage(cube, colors, true);
            
            %%%%% Unmix Image
            
            % Compute A matrix
            im.A = getAmatrix(spec, im.wl, filetype);
            
            
            % try
            % Analyze image
            switch cfg.algorithm
                case 'gnnls'
                    % Make sure gpu was found
                    if ~cfg.gpuUnmix
                        ME = 'nogpu';
                        return;
                    end
                    % Unmix using GPU
                    [x, r, t_eval] = NNLSGPU(im.A, cube, cfg.gpuIdx);
                    
                    
                case {'fnnls', 'lsqnonneg'}
                    % Unmix using CPU
                    [x, r, t_eval] = NNLSCPU(im.A, cube, cfg.algorithm);
                    
                    % Convert to ms
                    t_eval = t_eval*1000;
                    
                case {'fourier', 'chebyshev'}
                    % Unmix using phasorAnalysis
                    [x, r, t_eval] = phasorUnmix(im.A, cube, cfg.algorithm);
            end
            
            % catch ME
            %     warning('Could not unmix image %d of %d: %s \n\n %s \n\n', n, ...
            %         handles.num_images, handles.imLabels{n}, ME.message);
            %         throw(ME);
            %     return;
            % end
            
            %%%%% Post analsys
            % Do these things *after* unmixing
            
            % Calculate Reduced Chi-Squared
            % Degrees of freedom (# observations - # fitted params)
            dof = num_wl - num_spec;
            
            switch filetype
                case 'hyper'
                    % Poisson Factor (hyperCFME is super-possonian, measured with sigvar.m)
                    % p-factor is usually on the order of 10
                    pfactor = 2;
                    
                    % Sigma is the uncertainty of the measurement and dof is the number of
                    % degrees of freedom of system
                    sigma = sqrt(pfactor * cube);
                    
                    % Reduced Chi-Squared. Defined as the sum of the residual squared
                    % divided by the varience (uncertainty squared), all over the dof of
                    % the system.
                    temp = (r ./ sigma).^2;
                    
                    % Zero valued pixels in the image will give zeros for sigma, which
                    % makes redchi inf. Here, set these inf's to zero.
                    temp(isinf(temp)) = 0;
                    temp(isnan(temp)) = 0;
                    redchisq = sum(temp, 3 ) / dof;
                    
                    
                case {'envi', 'maestro', 'oir', 'oir-tiff', 'czi', 'slice'}
                    
                    % Assume uncertainty is +/- 10%
                    % sigma = (0.1 .* handles.cube{n});
                    
                    % Assume sigma = 1;
                    sigma = ones(size(r));
                    redchisq = sum((r ./ sigma).^2, 3) / dof;
            end
            
            % Miscellaneous - these are specific to hyperCFME images and are usually
            % not needed, so they aren't built in to the gui, but toggle the if
            % statements to include/exculde them in the analysis
            
            % Current spectra in A matrix
            names = {spec.name};
            
            % Define specific spectra to analyze
            % names_to_edit = {'AF633', 'AF647', 'AF660', 'AF680', 'AF700'};
            
            if false
                % Apply threshold levels to cut out noise
                % EGFR shows up in no-tumor control from spectral bleed-over /
                % overfitting.
                tld = [ 0.0009, ... AF633 - Transferrin
                    0.0010, ... AF647 - MUC16
                    0.0039, ... AF660 - CD44
                    0.0020, ... AF680 - CD45
                    0.0018]; %  AF700 - EGFR
                
                for i = 1:length(names_to_edit)
                    k   = strcmp(names, names_to_edit(i));
                    map = x(:,:,k);
                    map( map < tld(i) ) = 0;
                    x(:,:,k) = map;
                end
                
            end
            
            if false
                % Brightness calibration map
                % Ratios are from 5-color experiment
                lvl = [ 2.1258, ... AF633 - Transferrin
                    1.0000, ... AF647 - MUC16
                    8.0797, ... AF660 - CD44
                    1.1578, ... AF680 - CD45
                    1.7165]; %  AF700 - EGFR
                
                for i = 1:length(names_to_edit)
                    k   = strcmp(names, names_to_edit(i));
                    x(:,:,k) = x(:,:,k) * lvl(i);
                    
                end
                
            end
            
            
            if true
                % CD45 - inflamation mask
                i1  = strcmp(names, 'AF680-7-14-20');
                mask = x(:, :, i1) > 0.1 * max(max( x(:, :, i1) ));
                
                toMask = {'AF633-v2-7-14-20'; 'AF660-7-14-20'; 'AF700-v3-7-14-20'};
                [~, ~, maskidx] = intersect(toMask, names, 'stable');
                
                mask = repmat(mask, [1 1 numel(maskidx)]);
                
                % Apply mask
                x(:, :, maskidx) = x(:, :, maskidx) .* ~mask;
                
                for i = 1:num_spec
                    x(:, :, i) = imfilter(x(:, :, i), cfg.gaussfilt, 'replicate');
                end
            end
            
            
            if true
                %Adjust EGFR channel
                i2  = strcmp(names, 'AF700-3');
                x(:, :, i2) = x(:, :, i2) * 2;
                
                %     % Adjust CD44
                %     i2  = strcmp(names, 'AF660');
                %     x(:, :, i2) = x(:, :, i2) * 0.7;
            end
            
            % Saturated pixel mask on the output
            if state.satpix
                x = x .* repmat(im.satpix, [1 1 num_spec]);
                redchisq = redchisq .* im.satpix;
            end
            
            % Pseudocolor basis images
            colors = rgb({spec.color});
            im.colorims.basis = colorImage(x, colors, true);
            
            % Image is now unmixed (or skipped)
            im.isUnmixed = true;
            
            %OUTPUTS: Update handles
            im.disp     = cube;
            im.x        = x;
            im.redchisq = redchisq;
            im.evalTime = t_eval;
        end
        
        function handles = displayImage(handles)
            %% displayImage - update the GUI display
            
            % If no experiment, do nothing
            if ~handles.expt.loaded
                handles.msgbox.String = handles.msg.loadexpt;
                return;
            end
            
            % Current selected image
            n = handles.state.img;
            
            if strcmp(handles.img(n).filename, 'NOT_LOADED')
                % File was not loaded properly the first time, skip it
                handles.msgbox.String = 'File not loaded properly.';
                return;
            end
            
            % Local varaibles
            cfg   = handles.cfg;
            state = handles.state;
            spec  = handles.spec;
            expt  = handles.expt;
            m     = state.spec;
            
            % Check if image has been loaded
            if ~handles.img(n).isLoaded
                % If not, load image
                
                % Inform user
                handles.msgbox.String = handles.msg.loadingcube;
                
                [handles.img(n), ME] = getImageCube(handles.img(n), expt.filetype);
                
                % Update filename before checking the error
                handles.img_listbox.String = {handles.img.filename};
                
                if ~isempty(ME)
                    handles.msgbox.String = ME;
                    return;
                end
                
            else
                % Update filename
                handles.img_listbox.String = {handles.img.filename};
            end


            % Update filename above listbox
            handles.text_filename.String = handles.img(n).label;
            
            % If no Basis spectra selected for unmixing, do nothing
            if isempty([spec.idx])
                handles.msgbox.String = handles.msg.loadBasis;
                return;
            end
            
            % Extra check incase cube was not found
            if isempty(handles.img(n).cube)
                % Image wasn't loaded
                handles.msgbox.String = 'This file was not loaded properly.';
                return;
            end
            
            % Analyze and unmix image if needed
            if ~handles.img(n).isUnmixed
                
                % Inform user
                handles.msgbox.String = handles.msg.unmixing; drawnow;
                
                % Unmix image
                [handles.img(n), ME] = unmixImage(handles.img(n), handles);
                if ~isempty(ME)
                    handles.msgbox.String = handles.msg.nogpu;
                    return;
                end
                
                % Get ROI stats
                handles.img(n).roiStats = getRegionStats(handles.img(n), handles.img(n).roi.mask);
            end
            
            handles.msgbox.String = 'Finalizing Images... Please Wait.';
            
            % Format evaluation time
            if handles.img(n).evalTime >= 1000 % If greater than 1000 ms, convert to sec
                timestr = sprintf('%3.1f s', handles.img(n).evalTime/1000);
            else % keep in milliseconds
                timestr = sprintf('%3.1f ms', handles.img(n).evalTime);
            end
            
            % Display time
            handles.text_time.String = timestr;
            
            % Display selcted image on axes1
            axes(handles.axes1); cla;
            switch handles.display_ax1_popupmenu.Value
                case 1 % Basis spectra composite image
                    
                    specfilt = logical([handles.spec.filt]); % In case filt was not a bool
                    im = sum(cat(4, handles.img(n).colorims.basis(specfilt).im), 4) ...
                        * state.brightness; % / sum(specfilt);
                    
                    % Display composite image
                    imObj = imshow(im);
                    axis tight; colorbar('off');
                    
                case 2 % Raw image
                    
                    % Raw image is normalized so don't multiply by brightness
                    wlfilt = [handles.img(n).filt];
                    im = sum(cat(4, handles.img(n).colorims.cube(wlfilt).im), 4) / sum(wlfilt);
                    
                    % Adjust contrast
                    im = imadjust(im, stretchlim(im, 0), []);
                    
                    % Display raw image
                    imObj = imshow(im);
                    axis tight; colorbar('off');
                    
                case 3 % Chi-squared map
                    
                    % Display Red. Chi-Squared Map
                    imObj = imagesc(handles.img(n).redchisq, 'hittest', 'off');
                    axis tight;
                    handles.axes1.XTickLabel = [];
                    handles.axes1.YTickLabel = [];
                    colorbar;
            end
            
            % Give the image context menu properties
            set(imObj, 'uicontextmenu', handles.axes1_cmenu);
            % handles.imObj = imObj;
            hold on;
            
            % Current ROI index
            if state.globalroi
                roiIdx = cfg.roiIdx;
            else
                roiIdx = n;
            end
            
            % Display ROIs
            for a = 1:length(handles.img(roiIdx).roi)
                roi = handles.img(roiIdx).roi(a).mask;
                
                % Plot roi
                if isempty(handles.img(roiIdx).roi(a).pts)
                    % Empty roi, skip plot
                else
                    switch handles.img(roiIdx).roi(a).type
                        case 'boundary'
                            plot(handles.img(roiIdx).roi(a).pts(:,1), handles.img(roiIdx).roi(a).pts(:,2), ...
                                '.--', 'Color', cfg.roiColors{a}, 'MarkerSize', 15, 'LineWidth', 2);
                        case 'points'
                            plot(handles.img(roiIdx).roi(a).pts(:,1), handles.img(roiIdx).roi(a).pts(:,2), ...
                                '.', 'Color', cfg.roiColors{a}, 'MarkerSize', 1);
                    end
                end
            end
            
            % Display the selected basis image(s) on axes2
            axes(handles.axes2); cla;
            im = sum(cat(4, handles.img(n).colorims.basis(m).im), 4) * state.brightness;
            imObj = imshow(im);
            set(imObj, 'uicontextmenu', handles.axes1_cmenu);
            
            % Initialize vars needed for multiple axes3 options
            A  = handles.img(n).A;
            wl = handles.img(n).wl;
            
            % Set white color to grey for plotting
            slabels = {spec.label};
            scolors = {spec.color};
            try
                scolors{strcmp(scolors, 'White')} = 'Grey';
            catch
            end
            
            % Display axes3
            axes(handles.axes3); cla; hold on;
            set(gca, 'YScale', 'lin');
            
            switch handles.display_ax3_popupmenu.Value
                
                case 1 % Spectral Fit
                    
                    % Intialize data
                    savg = handles.img(n).roiStats(end).savg;
                    sstd = handles.img(n).roiStats(end).sstd;
                    xavg = handles.img(n).roiStats(end).xavg;
                    xstd = handles.img(n).roiStats(end).xstd;
                    
                    % Convert x to percentages
                    xavg_norm = 100 * xavg / sum(xavg);
                    xstd_norm = 100 * xstd;
                    
                    % Avg. Spectrum in ROI
                    if state.errorbars
                        % Plot error bars, but only if averaged spectra
                        % Point ROI's do not have error bars...
                        if isempty(sstd)
                            plot(wl, savg, 'k');
                        else
                            errorbar(wl, savg, sstd, 'k');
                        end
                    else
                        % Regular ol' plot
                        plot(wl, savg, 'k');
                    end
                    legendNames{1} = 'ROI Avg. Spec.';
                    
                    % Fit A * x
                    plot(wl, A*xavg, 'k--');
                    legendNames{2} = 'Fit A*x';
                    
                    % Linewidth of each spectrum (the selected spectra get bolded)
                    linewidth    = ones(cfg.num_spec, 1);
                    linewidth(m) = 2;
                    
                    % Made 2 plots already, count the rest
                    j = 3;
                    for i = 1:cfg.num_spec
                        if xavg(i) > 0 % Only plot if spectra is non-zero
                            
                            % Plot idv. spectra weight by unmixing coef.
                            plot(wl, xavg(i)*A(:,i), 'Color', rgb(scolors{i}),...
                                'LineWidth', linewidth(i));
                            
                            % Keep track of legend
                            if true % isempty(xstd_norm)
                                legendNames{j} = sprintf('%s - %3.2f%%', ...
                                    slabels{i}, xavg_norm(i));
                            else
                                legendNames{j} = sprintf('%s - %3.2f +/- %3.2f%%', ...
                                    slabels{i}, xavg_norm(i), xstd_norm(i));
                            end
                            j = j + 1;
                        end
                    end
                    
                    t = ['ROI Composition \chi^2_{avg}' sprintf('= %4.3e', handles.img(n).roiStats(end).mchisq)];
                    title(t);
                    xlabel('Wavelength (nm)');
                    ylabel('Intensity (a.u.)');
                    xlim([min(wl) max(wl)]);
                    ylim([0 inf]);
                    
                    if state.legend
                        legend(legendNames, 'Location', 'best');
                        legend('boxon');
                    else
                        legend off
                    end
                    
                case 2 % Histogram
                    
                    % In case multiple spectra are selected
                    roi = repmat(roi, [1 1 length(m)]);
                    
                    % Mask data
                    data = handles.img(n).x(:, :, m) .* roi;
                    
                    % Get rid of zeros
                    data( data == 0 ) = [];
                    
                    % Display
                    histogram(data);
                    if numel(m) == 1
                        t = sprintf('Histogram of Basis Map - %s', slabels{m});
                    else
                        t = sprintf('Histogram of %d Basis Maps', numel(m));
                    end
                    title(t);
                    xlabel('Intensity - 10-bit scale');
                    ylabel('Counts');
                    xlim([0 1]);
                    ylim([0 inf]);
                    
                case {3, 4} % Phasor plots
                    
                    % Reshape image and mask into columns of pixels
                    pixels = permute(handles.img(n).disp, [3 2 1]);
                    %         roi    = permute(roi, [3 2 1]);
                    pixels = pixels(:,:);
                    %         roi    = roi(:,:);
                    
                    % Mask 2D image
                    %         pixels = pixels(:, logical(roi));
                    
                    if handles.display_ax3_popupmenu.Value == 3 % Fourier
                        
                        p = cfg.phasor.f;
                        T     = fftCoefs(pixels, p:(p+1));
                        Tspec = fftCoefs(A,      p:(p+1));
                        
                        xlab = 'G'; ylab = 'S';
                        titlestr = sprintf('Fourier Phasor plot, n = %d', cfg.phasor.f);
                        
                    elseif handles.display_ax3_popupmenu.Value == 4 % Chebyshev
                        
                        p     = cfg.phasor.c;
                        T     = fctCoefs(pixels, p:(p+1));
                        Tspec = fctCoefs(A,      p:(p+1));
                        
                        xlab = 'U_{n}'; ylab = 'U_{n+1}';
                        titlestr = sprintf('Chebyshev Phasor plot, n = %d', cfg.phasor.c);
                        
                    end
                    
                    % Calculate heatmap colorscheme for scatter plot
                    heatmap = heatscatter(T(1,:)', T(2,:)');
                    
                    % Scatter plot with heatmap
                    handles.scat_pts = scatter(T(1,:), T(2,:), 10, heatmap, '.'); hold on;
                    
                    % Plot pixels in roi
                    for a = 1:length(handles.img(roiIdx).roi)
                        roi = handles.img(roiIdx).roi(a).mask;
                        roi = permute(roi, [3 2 1]);
                        roi = roi(:,:);
                        
                        if sum(roi) < numel(roi)
                            handles.scat_basis = scatter(T(1, logical(roi)), T(2, logical(roi)), 10, cfg.roiColors{a}, '.');
                        end
                        
                    end
                    % Add basis spectra
                    handles.scat_basis = scatter(Tspec(1,:), Tspec(2,:), 100, rgb(scolors), 'filled');
                    set(gca, 'XLimMode', 'auto'); set(gca, 'YLimMode', 'auto');
                    %         xlim([0 inf]); ylim([0 inf]);
                    xlabel(xlab); ylabel(ylab); title(titlestr);
                    
                    if state.legend
                        legend({'Phasor Points', 'Basis Spectra'}, 'Location', 'best');
                        colorbar;
                    else
                        legend off
                        colorbar off;
                    end
                    
            end
            
            % Format spec roi for prism
            if false
                % Intialize data
                savg = handles.img(n).roiStats.savg;
                sstd = handles.img(n).roiStats.sstd;
                xavg = handles.img(n).roiStats.xavg;
                xstd = handles.img(n).roiStats.xstd;
                npx  = handles.img(n).roiStats.npx;
                npxvec = repmat(npx, [1 length(wl)]);
                j = 1;
                %     assignin('base', 'A', A);
                %     assignin('base', 'xavg', xavg);
                %     assignin('base', 'savg', savg);
                
                for i = 1:3:((cfg.num_spec+2)*3)
                    if i == 1
                        
                        specdata2prism(:, i)     = savg;
                        specdata2prism(:, i + 1) = sstd / sqrt(npx);
                        specdata2prism(:, i + 2) = npxvec;
                        
                    elseif i == 4
                        
                        specdata2prism(:, i)     = A*xavg;
                        specdata2prism(:, i + 1) = A*xstd;
                        specdata2prism(:, i + 2) = npxvec;
                        
                    else
                        
                        specdata2prism(:, i)     = xavg(j)*A(:,j);
                        specdata2prism(:, i + 1) = xstd(j)*A(:,j);
                        specdata2prism(:, i + 2) = npxvec;
                        
                        j = j + 1;
                    end
                    
                    
                end
                
                % Sum-to-one norm
                xsum = sum(xavg);
                xavg = 100*xavg/xsum;
                xstd = 100*xstd/xsum;
                xdata2prism = zeros(1, length(xavg)*3);
                xdata2prism(1:3:end) = xavg;
                xdata2prism(2:3:end) = xstd;
                xdata2prism(3:3:end) = npx;
                
                % %     xdata2prism = [xavg(:) xstd(:) repmat(npx, [cfg.num_spec 1])];
                
                % Pass data to workspace
                assignin('base', 'specdata2prism', specdata2prism);
                assignin('base', 'xdata2prism',    xdata2prism);
                %     assignin('base', 'A', A);
                %     assignin('base', 'xavg', xavg);
                %     assignin('base', 'savg', savg);
                
            end
            
            
            handles.msgbox.String = 'Ready.';
            drawnow;
        end
        
        function roi = getRegion(dims, opt, varargin)
            %% getRegion - determine region mask and boundary points
            
            % size of image
            imx = dims(1);
            imy = dims(2);
            
            % Current axes
            axes(gca);
            
            switch opt
                
                case 'default'
                    roi.mask = ones(imx, imy);
                    roi.pts  = [0 0; 0 imx; imy imx; imy 0; 0 0];
                    roi.type = 'boundary';
                    
                case 'empty'
                    roi.mask = zeros(imx, imy);
                    roi.pts  = [];
                    roi.type = 'boundary';
                    
                case 'fibercore'
                    roi.pts = [127.687500000000,115.046875000000;121.906250000000,168.234375000000;138.093750000000,224.890625000000;169.312500000000,259.578125000000;220.187500000000,286.171875000000;283.781250000000,287.328125000000;334.656250000000,267.671875000000;370.500000000000,227.203125000000;387.843750000000,168.234375000000;383.218750000000,124.296875000000;365.875000000000,84.9843750000000;318.468750000000,44.5156250000000;252.562500000000,27.1718750000000;195.906250000000,41.0468750000000;153.125000000000,72.2656250000000;127.687500000000,115.046875000000];
                    roi.mask = poly2mask(roi.pts(:, 1), roi.pts(:, 2), imx, imy);
                    roi.type = 'boundary';
                    
                case 'point'
                    % Get point
                    [x, y] = ginput(1);
                    roi.mask = zeros(imx, imy);
                    roi.mask( round(y), round(x) ) = 1;
                    roi.pts  = [round(x) round(y)];
                    roi.type = 'boundary';
                    
                case 'roi'
                    % Get ROI
                    [roi.mask, x, y] = roipoly;
                    roi.pts = [x  y];
                    roi.type = 'boundary';
                    
                case 'scatter'
                    % Get data from scatter plot
                    pl = selectdata(varargin{1}{:});
                    
                    % Sometimes the ignore command doesn't work and 'pl' is returned as
                    % a cell array. Check for empty entries and remove them
                    % TODO: Hardcoded, fix this
                    if iscell(pl)
                        %             idx = find(cellfun('isempty',pl));
                        %             pl(idx) = [];
                        pl = pl{end};
                    end
                    
                    % *** Note that mask and pts are created using the transpose
                    % dimensions of the image. This is necessary because of the way
                    % matlab indexes images vs. matricies (transposed).
                    
                    % Create pixel mask
                    roi.mask = zeros(imy, imx);
                    roi.mask(pl) = 1;
                    roi.mask = roi.mask';
                    
                    % pixel points
                    [x, y]   = ind2sub([imy imx], pl);
                    roi.pts  = [x y];
                    roi.type = 'points';
                    
            end
            
        end
        
        function roiStats = getRegionStats(im, mask)
            %% getRegionStats - calculate means within an roi
            % Get data for analysis
            num_spec = size(im.x, 3);
            num_wl   = size(im.disp, 3);
            
            if isempty(im.cube)
                % Image not loaded
                return;
            end
            
            if im.imx ~= size(mask, 1) || im.imy ~= size(mask, 2)
                % Image and mask dimension are not equal
                return;
            end
            
            % Number of pixels in ROI
            num_px = sum(sum(mask));
            
            % 3D masks
            image_mask = logical(repmat(mask, [1 1 num_wl]));
            basis_mask = logical(repmat(mask, [1 1 num_spec]));
            
            % Keep spectral pixels included in mask (rows of pixels)
            maskIm = reshape(im.disp(image_mask), [num_px num_wl]);
            
            % Keep basis coeficients in mask (rows of coefs)
            maskX = reshape(im.x(basis_mask), [num_px num_spec]);
            
            if num_px == 1
                % Roi contatins one point, don't average
                savg = maskIm'; sstd = [];
                xavg = maskX';  xstd = [];
                
                % Normalize spectra and fit coefs (sum-to-one norm)
                smax = max(savg); %xsum = sum(xavg);
                savg = savg/smax; xavg = xavg/smax;
                
                avgRCS = im.redchisq(logical(mask));
                
            else
                % Average pixel spectra in ROI
                savg = mean(maskIm)'; sstd = std(maskIm)';
                
                % Average x values in ROI
                xavg = mean(maskX)'; xstd = std(maskX)';
                
                % Normalize spectra and fit coefs
                smax = max(savg); %xsum = sum(xavg);
                savg = savg/smax; xavg = xavg/smax;
                sstd = sstd/smax; xstd = xstd/smax;
                
                % Average redchisq pixels
                avgRCS = mean(mean(im.redchisq(logical(mask))));
            end
            
            % OUTPUTS: Update handles structure
            roiStats.savg   = savg;
            roiStats.sstd   = sstd;
            roiStats.xavg   = xavg;
            roiStats.xstd   = xstd;
            roiStats.mchisq = avgRCS;
            roiStats.npx    = num_px;
        end
        

        function writeImages(im, save_dir, handles)
            
            % Write colored composite raw-image
            wlfilt = [im.filt];
            imwrite( sum(cat(4, im.colorims.cube(wlfilt).im), 4), ... / sum(wlfilt), ...
                [save_dir 'raw_composite_image.tif'] );
            
            % Write colored composite basis-image
            specfilt = [handles.spec.filt];
            try
                imwrite( sum(cat(4, im.colorims.basis(specfilt).im), 4), ...
                    [save_dir 'basis_composite_image.tif'] );
            catch
                imwrite( sum(cat(4, im.colorims.basis.im), 4), ...
                    [save_dir 'basis_composite_image.tif'] );
            end
            % % Write the saturated pixel mask image
            % imwrite( uint16(L_sat_all .* 2^16), ...
            %     [save_dir 'saturated pixel mask' '.tif'] );
            
            % Write chi-squared map
            f = figure('visible', 'off');
            imagesc(im.redchisq);
            axis square
            set(gca, 'XTickLabel', [], 'YTickLabel', []);
            set(f, 'Color', 'none');
            colorbar; colormap jet;
            export_fig(f, [save_dir 'ChiSquaredMap.png'], '-native');
            clear f;
            
            % Calculate diff between CPU & GPU
            % if handles.gpuUnmix
            if false
                
                x_gpu = im.x;  %#ok<*UNRCH>
                [x_cpu, ~, ~] = unmixSpeedTest(im.A, im.cube, 1, 'fnnls', [0 0]);
                
                % RMS Error
                rms = sqrt(mean((x_cpu{1} - x_gpu).^2, 3));
                %
                %     % Write & save rmse map
                save([save_dir filesep 'RMSE_CPU_GPU.mat'], 'rms');
                
                f = figure('visible', 'off');
                imagesc(rms);
                axis image
                set(gca, 'XTickLabel', [], 'YTickLabel', []);
                set(f, 'Color', 'none');
                colorbar; colormap jet;
                export_fig(f, [save_dir 'CPU_GPU_difference.png'], '-native');
                clear f;
                
                % else
                %
                %     x_cpu = handles.Coefcube{n};
                %     x_gpu = [];
                %     rms   = [];
                %
            end
            
            % Save spectral data in roi
            if true
                save([save_dir 'analysis_data.mat'], 'im');
            end
            
            for i = 1:handles.cfg.num_spec
                
                % Save Color Images
                imwrite( im.colorims.basis(i).im * handles.state.brightness, ...
                    [save_dir 'basis_image_' ...
                    handles.spec(i).label '.tif'] );
                
                % Save Raw basis maps as 16-bit tiffs
                imwrite( uint16(im.x(:,:,i) * 2^16), ...
                    [save_dir 'basis_image_intensity_map_' ...
                    handles.spec(i).name '.tif'] );
                
            end
            
            if false
                % Generate tiled image
                tiles = makeTiledImage(im, handles);
                
                parts = strsplit(handles.expt.dirname, filesep);
                
                % Save image
                imwrite(tiles, [save_dir 'tiled_basis_images_' im.label '_' parts{end} '.tif']);
                
                %     extra_dir = '/Users/eric/Documents/hyperCFME/data/5ColorDyeSoln-28ImgAvg-20NOV/Tiles/';
                %     imwrite(tiles, [extra_dir 'tiled_basis_images_' im.label '_' parts{end} '.tif']);
            end
        end
        
        function writeBasisAsTiffStack(im, save_dir, subdirs, filename, handles)
            
            % Write colored composite basis-image
            specfilt = [handles.spec.filt];
            try
                imwrite( sum(cat(4, im.colorims.basis(specfilt).im), 4), ...
                    [save_dir subdirs{1} filename 'basis_composite_image.tif'] );
            catch
                imwrite( sum(cat(4, im.colorims.basis.im), 4), ...
                    [save_dir subdirs{1} filename 'basis_composite_image.tif'] );
            end
            
            % Save spectral data in roi
            % if true
            %     save([save_dir 'analysis_data.mat'], 'im');
            % end
            
            tColor = Tiff([save_dir subdirs{2} filename 'basis_stack_color.tif'],'w');
            tBW = Tiff([save_dir subdirs{3} filename 'basis_stack_intensity_map.tif'],'w');
            
            tagstructC.ImageLength = size(im.colorims.basis(1).im, 1);
            tagstructC.ImageWidth = size(im.colorims.basis(1).im, 2);
            tagstructC.Photometric = Tiff.Photometric.RGB;
            tagstructC.BitsPerSample = 16;
            tagstructC.SamplesPerPixel = 3;
            tagstructC.Compression = Tiff.Compression.None;
            tagstructC.SampleFormat = Tiff.SampleFormat.UInt;
            tagstructC.RowsPerStrip = 512;
            tagstructC.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            tagstructC.ImageDescription = 'min=0.0 max=65535.0';
            
            tagstructBW.ImageLength = size(im.colorims.basis(1).im, 1);
            tagstructBW.ImageWidth = size(im.colorims.basis(1).im, 2);
            tagstructBW.Photometric = Tiff.Photometric.MinIsBlack;
            tagstructBW.BitsPerSample = 16;
            tagstructBW.SamplesPerPixel = 1;
            tagstructBW.Compression = Tiff.Compression.None;
            tagstructBW.SampleFormat = Tiff.SampleFormat.UInt;
            tagstructBW.RowsPerStrip = 512;
            tagstructBW.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            tagstructBW.ImageDescription = 'min=0.0 max=65535.0';
            
            for i = 1:handles.cfg.num_spec
                tColor.setTag(tagstructC);
                tBW.setTag(tagstructBW);
                write(tColor, uint16(im.colorims.basis(i).im * 65535));
                write(tBW, uint16(im.x(:,:,i) * 65535)),
                if i ~= length(im.colorims.basis)
                    tColor.writeDirectory();
                    tBW.writeDirectory();
                end
                
            end
            
            close(tColor);
            close(tBW);
        end
        
        function writeVideoFiles(sequence, vid_path, fps, handles)
            % Wrapper for VideoWriter function
            
            % Initialize
            VidObj = VideoWriter( vid_path, 'MPEG-4');
            VidObj.FrameRate = fps;
            open(VidObj)
            
            % Loop through image sequence, load each image, write it to the video.
            for i = 1:length(sequence)
                
                % Skip empty frames
                if isempty(sequence{i})
                    continue;
                end
                
                % Get frame and normalize
                frame = mat2gray(sequence{i} * handles.state.brightness);
                
                % Write frame to video
                writeVideo(VidObj, frame);
                
                % Inform user
                str = sprintf('Writing %d of %d images to %s...', ...
                    i, handles.cfg.num_imgs, vid_path);
                handles.msgbox.String = str;
                drawnow;
            end
            close(VidObj);
        end
        
        function handles = ffcorrect(handles)
            
            handles.msgbox.String = 'Calculating flat-field correction...'; drawnow;
            
            n = handles.num_images;
            m = handles.num_spec;
            x = handles.Coefcube;
            
            % Pseduocolor images
            
            fidx = handles.compIdx(handles.matidx);
            if handles.inclLaser
                % Set all images to be included in composite
                fidx = true(size(fidx));
            end
            colors = rgb(handles.specColors(handles.matidx));
            
            % Get sum of basis maps across expt, convert to 16-bit scale
            allData = cat(3, x{:}) * 2^16;
            ff = zeros(size(allData, 1), size(allData, 2), m);
            
            % test = cat(2, x{:}) * 2^16;
            
            for i = 1:m
                
                ff(:, :, i) = sum(allData(:, :, i:m:end), 3);
                
            end
            
            % Local Maximum disk filter
            ff = imdilate(ff, strel('disk',45,0));
            
            % Gaussian blur to smooth edges
            ff = imgaussfilt(ff, 10);
            
            % Set any zero values to 1
            ff( ff == 0 ) = 1;
            
            % Normalize each map back to 1
            norm = repmat(max(max(ff)), [size(ff, 1) size(ff, 2) 1]);
            ff   = ff ./ norm;
            
            % Divide basis maps by flatfield correction
            for i = 1:n
                try
                    % Flat Field correction
                    x{i} = x{i} ./ ff;
                    
                    % Normalize
                    %         x{i} = x{i} ./ max(max(max(x{i})));
                    
                    % Gaussian blur
                    %         for j = 1:m
                    %             x{i}(:,:,j) = imgaussfilt(x{i}(:,:,j), 2);
                    %         end
                    
                    % Color image
                    [handles.compIm{i}, handles.basisIms(1:m, i)] = colorImage(x{i}, colors, fidx);
                    
                    handles.msgbox.String = ['Applying flat-field correction.... ' ...
                        sprintf('%3.0f%%', handles.percent_load(i))];
                    drawnow;
                    
                catch
                    
                end
            end
            
            % Save
            handles.ffcorrect = ff;
            handles.Coefcorr = x;
        end
        
        function [level, threshold] = isodata(I)
            %   ISODATA Compute global image threshold using iterative isodata method.
            %   LEVEL = ISODATA(I) computes a global threshold (LEVEL) that can be
            %   used to convert an intensity image to a binary image with IM2BW. LEVEL
            %   is a normalized intensity value that lies in the range [0, 1].
            %   This iterative technique for choosing a threshold was developed by Ridler and Calvard .
            %   The histogram is initially segmented into two parts using a starting threshold value such as 0 = 2B-1,
            %   half the maximum dynamic range.
            %   The sample mean (mf,0) of the gray values associated with the foreground pixels and the sample mean (mb,0)
            %   of the gray values associated with the background pixels are computed. A new threshold value 1 is now computed
            %   as the average of these two sample means. The process is repeated, based upon the new threshold,
            %   until the threshold value does not change any more.
            
            %
            %   Class Support
            %   -------------
            %   The input image I can be of class uint8, uint16, or double and it
            %   must be nonsparse.  LEVEL is a double scalar.
            %
            %   Example
            %   -------
            %       I = imread('blood1.tif');
            %       level = graythresh(I);
            %       BW = im2bw(I,level);
            %       imshow(BW)
            %
            %   See also IM2BW.
            %
            % Reference :T.W. Ridler, S. Calvard, Picture thresholding using an iterative selection method,
            %            IEEE Trans. System, Man and Cybernetics, SMC-8 (1978) 630-632.
            
            % Check for non-zero data
            maxI = max(max(I));
            if maxI == 0
                % Data is all zero...
                threshold = 0;
                level = 0;
                return;
            end
            
            % Normalization factor to 8-bit scale
            norm = 255 / maxI;
            
            % Convert all N-D arrays into a single column.  Convert to uint8 for
            % fastest histogram computation.
            I = im2uint8(I(:) * norm);
            
            % STEP 1: Compute mean intensity of image from histogram, set T=mean(I)
            [counts , N] = imhist(I);
            i    = 1;
            mu   = cumsum(counts);
            T(i) = sum( N .* counts ) / mu(end);
            T(i) = round(T(i));
            
            % STEP 2: compute Mean above T (MAT) and Mean below T (MBT) using T from
            % step 1
            mu2 = cumsum( counts(1:T(i)) );
            MBT = sum( N(1:T(i)) .* counts(1:T(i)) )  / mu2(end);
            
            mu3 = cumsum(counts(T(i):end));
            MAT = sum(N(T(i):end).*counts(T(i):end)) / mu3(end);
            i = i + 1;
            % new T = (MAT+MBT)/2
            T(i) = round((MAT+MBT)/2);
            
            % STEP 3 to n: repeat step 2 if T(i)~=T(i-1)
            while abs(T(i)-T(i-1))>=1
                mu2=cumsum(counts(1:T(i)));
                MBT=sum(N(1:T(i)).*counts(1:T(i)))/mu2(end);
                
                mu3=cumsum(counts(T(i):end));
                MAT=sum(N(T(i):end).*counts(T(i):end))/mu3(end);
                
                i=i+1;
                T(i)=round((MAT+MBT)/2);
                
            end
            
            threshold = T(i) / norm;
            % Normalize the threshold to the range [i, 1].
            level = (threshold - 1) / (N(end) - 1);
        end
        
        function scatter_COL = heatscatter(X, Y, numbins)
            
            % Adapted from https://www.mathworks.com/matlabcentral/fileexchange/...
            % 47165-heatscatter-plot-for-variables-x-and-y
            if ~exist('numbins','var') || isempty(numbins)
                numbins = 200;
            end
            
            [values, centers] = hist3([X Y], [numbins numbins]);
            centers_X = centers{1,1};
            centers_Y = centers{1,2};
            binsize_X = abs(centers_X(2) - centers_X(1)) / 2;
            binsize_Y = abs(centers_Y(2) - centers_Y(1)) / 2;
            bins_X = zeros(numbins, 2);
            bins_Y = zeros(numbins, 2);
            for i = 1:numbins
                bins_X(i, 1) = centers_X(i) - binsize_X;
                bins_X(i, 2) = centers_X(i) + binsize_X;
                bins_Y(i, 1) = centers_Y(i) - binsize_Y;
                bins_Y(i, 2) = centers_Y(i) + binsize_Y;
            end
            scatter_COL = zeros(length(X), 1);
            
            for i = 1:length(X)
                last_lower_X = NaN;
                last_higher_X = NaN;
                id_X = NaN;
                c_X = X(i);
                last_lower_X = find(c_X >= bins_X(:,1));
                if (~isempty(last_lower_X))
                    last_lower_X = last_lower_X(end);
                else
                    last_higher_X = find(c_X <= bins_X(:,2));
                    if (~isempty(last_higher_X))
                        last_higher_X = last_higher_X(1);
                    end
                end
                if (~isnan(last_lower_X))
                    id_X = last_lower_X;
                else
                    if (~isnan(last_higher_X))
                        id_X = last_higher_X;
                    end
                end
                last_lower_Y = NaN;
                last_higher_Y = NaN;
                id_Y = NaN;
                c_Y = Y(i);
                last_lower_Y = find(c_Y >= bins_Y(:,1));
                if (~isempty(last_lower_Y))
                    last_lower_Y = last_lower_Y(end);
                else
                    last_higher_Y = find(c_Y <= bins_Y(:,2));
                    if (~isempty(last_higher_Y))
                        last_higher_Y = last_higher_Y(1);
                    end
                end
                if (~isnan(last_lower_Y))
                    id_Y = last_lower_Y;
                else
                    if (~isnan(last_higher_Y))
                        id_Y = last_higher_Y;
                    end
                end
                
                try
                    scatter_COL(i) = values(id_X, id_Y);
                catch
                    scatter_COL(i) = NaN;
                end
            end
        end
        
        function tiles = makeTiledImage(img, handles)
            %% makeTiledImage(img, handles)
            
            % Set default parameters
            % TODO: Add inputdlg to let user define parameters
            sizex = 182; % For hyperCFME - 182 pxls = 200 um
            sizey = 182;
            pxlbuffer = 0;
            buffercolor = 'white';
            direction = 'horizontal';
            
            centerx = round((img.imx - sizex)/2);
            centery = round((img.imy - sizey)/2);
            
            % Default rectangle is centered
            h = imrect(handles.axes1, [centery centerx sizex sizey]);
            pos = wait(h);
            delete(h);
            
            x = [pos(1) pos(1)          (pos(1)+pos(3)) (pos(1)+pos(3)) pos(1)];
            y = [pos(2) (pos(2)+pos(4)) (pos(2)+pos(4)) pos(2)          pos(2)];
            
            mask = poly2mask(x, y, img.imx, img.imy);
            
            % Find boundaries of mask
            col1 = find(sum(mask, 1), 1, 'first');
            col2 = find(sum(mask, 1), 1, 'last' );
            row1 = find(sum(mask, 2), 1, 'first');
            row2 = find(sum(mask, 2), 1, 'last' );
            
            % Build tiles
            
            % Number of basis ims
            m = handles.cfg.num_spec + 1;
            
            % Calculate total size of tile
            switch direction
                case 'horizontal'
                    sizey       = sizey + pxlbuffer;
                    sizex_total = sizex;
                    sizey_total = sizey*m - pxlbuffer;
                case 'vertical'
                    sizex       = sizex + pixlebuffer;
                    sizex_total = sizex*m - pxlbuffer;
                    sizey_total = sizey;
                    
            end
            
            %Initialize image as the buffer color
            rgbvec = reshape(rgb(buffercolor), [1 1 3]);
            tiles = repmat(rgbvec, [sizex_total, sizey_total]);
            
            % Loop through images
            for i = 1:m
                
                % Update new idx
                switch direction
                    case 'vertical'
                        x1 = (i - 1)*sizex + 1;
                        x2 = x1 + sizex - pxlbuffer - 1;
                        y1 = 1;
                        y2 = sizey;
                    case 'horizontal'
                        x1 = 1;
                        x2 = sizex;
                        y1 = (i - 1)*sizey + 1;
                        y2 = y1 + sizey - pxlbuffer - 1;
                        
                end
                
                % Put composite first, then indv basis
                if i == 1
                    % Compute composite image
                    im = sum(cat(4, img.colorims.basis([handles.spec.filt]).im), 4);
                    
                    % Crop
                    im = im(row1:row2, col1:col2, :);
                    
                    % Grab limts for contrast auto-adjust;
                    %         lims = stretchlim(im, 0);
                else
                    im = img.colorims.basis(i - 1).im(row1:row2, col1:col2, :);
                end
                
                % Adjust contrast
                %     im = imadjust(im, lims, []);
                
                % Place into tiled image
                tiles(x1:x2, y1:y2, :) = im;
                
                
            end
        end
        
        % --------------------------------------------------------------------
        function debug_code_menu_Callback(hObject, ~, handles)
            % hObject    handle to debug_code_menu (see GCBO)
            % ~  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            guidata(hObject, handles);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add library to MATLAB Path.
            addpath(genpath([cd filesep 'lib']));
            
            % Check for demo folder
            if isempty(genpath([cd filesep 'demo']))
                warning(sprintf(['Demo folder not detected, demos will not work. \n\n' ...
                    'Download the demo folder from www.springlabnu.com and copy to' ...
                    'Hyperviewer working directory'])); %#ok<SPWRN>
            else
                addpath(genpath([cd filesep 'demo']));
            end
            
            % Set default msgbox string:
            app.msgbox.String = 'Click Load Experiment to begin.';
            
            
            %% Processor settings
            
            % Check computer OS
            app.cfg.ARCHSTR = computer('arch');
            
            % Universal algorithm configurations (don't need gpu)
            algstr{1} = 'CPU - FNNLS';
            algstr{2} = 'CPU - LSQNONNEG';
            algstr{3} = 'CPU - Fourier Phasor';
            algstr{4} = 'CPU - Chebyshev Phasor';
            
            numDefAlg = length(algstr);
            
            % Look for compatible GPUs on machine
            app.cfg.gpuDevCount = gpuDeviceCount;
            % handles.gpuDevCount = 0;
            
            % Check for parpool. If no pool, do not create new one.
            app.cfg.poolobj = gcp('nocreate');
            
            if app.cfg.gpuDevCount == 0 % No gpu was found
                
                % Disable GPU unmixing
                app.cfg.gpuUnmix = false;
                app.cfg.algorithm = 'fnnls';
                
                if isempty(app.cfg.poolobj)
                    
                    % Query user to start a parpool (NNLSCPU is parfor enabled)
                    response = questdlg([app.msg.nogpu 'Would you like to enable ' ...
                        'the Parallel Computation Toolbox to speed up the '     ...
                        'analysis?'], 'WARNING: No GPU Device Found.', ...
                        'Yes', 'No', 'Yes');
                    
                    switch response
                        case 'Yes'
                            % Enable parpool
                            app.cfg.poolobj = parpool('local');
                            
                            
                            % Infom user about parpool
                            handles.msgbox.String = app.msg.cpu_withparpool;
                            
                        case 'No'
                            % Do nothing
                            % Inform user about algorithm status.
                            handles.msgbox.String = app.msg.cpu_noparpool;
                    end
                    
                end
                
                
            else  % At least 1 GPU was found. TODO: Add option to choose number of GPU
                
                % Enable GPU unmixing.
                app.cfg.gpuUnmix = true;
                app.cfg.algorithm = 'gnnls';
                handles.msgbox.String = app.msg.gpuEnabled;
                
                % Get gpu device names
                for i = 1:app.cfg.gpuDevCount
                    app.cfg.gpuInfo{i} = gpuDevice(i);
                    
                    % Add to list of algortim cfgs (4 defaults exist from above)
                    algstr{i + numDefAlg} = ['GPU - ' app.cfg.gpuInfo{i}.Name];
                end
                
                app.cfg.gpuIdx = [zeros(1, app.cfg.gpuDevCount) 0];
            end
            
            % Set string values
            handles.cpugpu_popupmenu.String = algstr;
            
            % Defalut algorithm cfg
            if app.cfg.gpuUnmix
                % Start with first gpu device
                gpuDevice(1);
                app.cfg.gpuIdx(1) = 1;
                handles.cpugpu_popupmenu.Value = numDefAlg + 1;
            else
                handles.cpugpu_popupmenu.Value = 1;
            end
            
            
            %%  Load Basis Spectra File
            load(app.expt.bslFile);
            
            % Set BSL and load spec names, colors, labels
            app.lib = loadBSL(BSL, 'all', false);
            
            % Update BSL listbox
            app.state.bsl_list = {lib.tags}';
            
            % Default A matrix is empty
            [lib.matidx] = deal(false);
            
            % Load spectra
            [spec, app.lib] = loadSpectra(app);
            
            % Update A_mat listbox
            app.state.mat_list = {spec.tags}';
            
            %% Default parameters
            
            % Analysis params
            % How many pixels is the image over sampled?
            app.cfg.compress.x = 1;
            app.cfg.compress.y = 6;
            
            % Default harmonics for phasor plots
            app.cfg.phasor.f = 1;
            app.cfg.phasor.c = 2;
            
            % Gaussian mean filter to remove fiber cores
            app.cfg.gaussfilt = fspecial('gaussian', [6 6], app.cfg.sigma);
            
            app.cfg.roiColors = {[0.8 0.8 0.8], 'Green', 'Cyan', 'Yellow', 'Magenta', 'Red'};
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {726, 726, 726};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {726, 726};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {287, '1x', 426};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: UnmixAllButton
        function UnmixAllButtonPushed(app, event)
            
            if ~app.expt.loaded
                app.msgbox.String = app.msg.loadexpt;
                return;
            end
            
            handles.msgbox.String = 'Unmixing all images...  0%';
            
            % Switch to axes3 to display unmixing stats
            handles.axes3; hold on;
            title('Unmixing Speed');
            xlabel('Image #');
            ylabel('Time (s)');
            xlim([0 handles.cfg.num_imgs]);
            legend off;
            set(gca, 'YScale', 'log');
            
            
            t_load = zeros(1, handles.cfg.num_imgs);
            lag    = zeros(1, handles.cfg.num_imgs);
            
            % Analyze images images that have not been unmixed.
            for n = 1:handles.cfg.num_imgs
                
                % If image has not been loaded, load it
                if ~handles.img(n).isLoaded
                    
                    % Load image
                    tic
                    [handles.img(n), ME] = getImageCube(handles.img(n), handles.expt.filetype);
                    t_load(n) = toc;
                    
                    % Update filename before checking the error
                    handles.img_listbox.String = {handles.img.filename};
                    
                    if ~isempty(ME)
                        handles.msgbox.String = ME;
                        warning(ME);
                    end
                end
                
                
                % Unmix image (here, do not skip the analysis if already done,
                % this makes sure all images are analyzed with the same parameters)
                if strcmp(handles.img(n).filename, 'NOT_LOADED')
                    continue;
                else
                    [handles.img(n), ME] = unmixImage(handles.img(n), handles);
                end
                if ~isempty(ME)
                    handles.msgbox.String = handles.msg.nogpu;
                    warning(ME);
                end
                
                % Get region stats
                handles.img(n).roiStats = getRegionStats(handles.img(n), handles.img(handles.cfg.roiIdx).roi.mask);
                
                lag(n) = t_load(n) - handles.img(n).evalTime;
                plot(n, t_load(n), 'b*', n, lag(n), 'ro');
                ylim([-inf inf]);
                
                handles.msgbox.String = sprintf('Unmixing all images... %1.0f %%', ...
                    handles.expt.loadvec(n));
                drawnow;
                
            end
            
            handles.msgbox.String = 'Ready.';
            % Obsolete, delete all guidata functions
            %% guidata(hObject, handles);
        end

        % Menu selected function: hyperCFMEMenu
        function hyperCFMEMenuSelected(app, event)
            app.expt.filetype = 'hyper';
            loadExperiment(app);
        end

        % Menu selected function: MaestroMenu
        function MaestroMenuSelected(app, event)
            app.expt.filetype = 'maestro';
            loadExperiment(app);
        end

        % Menu selected function: BioFormatsMenu
        function BioFormatsMenuSelected(app, event)
            app.expt.filetype = 'bioformat';
            loadExperiment(app);
        end

        % Menu selected function: DavisLabMenu
        function DavisLabMenuSelected(app, event)
            app.expt.filetype = 'macrotome';
            loadExperiment(app);
        end

        % Menu selected function: ENVIMenu
        function ENVIMenuSelected(app, event)
            app.expt.filetype = 'envi';
            loadExperiment(app);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % Add function to open a previously analyzed file
            dummy = 1;
        end

        % Menu selected function: ReloadMenu
        function ReloadMenuSelected(app, event)
            
        end

        % Menu selected function: AnalysisMenu
        function AnalysisMenuSelected(app, event)
            
        end

        % Menu selected function: AllAnalysesMenu
        function AllAnalysesMenuSelected(app, event)
            
        end

        % Menu selected function: AsTiffStacksMenu
        function AsTiffStacksMenuSelected(app, event)
            
        end

        % Menu selected function: NewImageMenu
        function NewImageMenuSelected(app, event)
            
        end

        % Menu selected function: MovieMenu
        function MovieMenuSelected(app, event)
            
        end

        % Menu selected function: NewBasisSpectraMenu
        function NewBasisSpectraMenuSelected(app, event)
            
        end

        % Menu selected function: TilesMenu
        function TilesMenuSelected(app, event)
            
        end

        % Menu selected function: NewROIMenu
        function NewROIMenuSelected(app, event)
            
        end

        % Menu selected function: AddROIMenu
        function AddROIMenuSelected(app, event)
            
        end

        % Menu selected function: NewPointMenu
        function NewPointMenuSelected(app, event)
            
        end

        % Menu selected function: AddPointMenu
        function AddPointMenuSelected(app, event)
            
        end

        % Menu selected function: AddROItoBSLMenu
        function AddROItoBSLMenuSelected(app, event)
            
        end

        % Menu selected function: ClearLastROIMenu
        function ClearLastROIMenuSelected(app, event)
            
        end

        % Menu selected function: PreunmixingMenu
        function PreunmixingMenuSelected(app, event)
            
        end

        % Menu selected function: PostunmixingMenu
        function PostunmixingMenuSelected(app, event)
            
        end

        % Menu selected function: SpectraViewerMenu
        function SpectraViewerMenuSelected(app, event)
            
        end

        % Menu selected function: SpeedtestCPUMenu
        function SpeedtestCPUMenuSelected(app, event)
            
        end

        % Menu selected function: SpeedtestGPUMenu
        function SpeedtestGPUMenuSelected(app, event)
            
        end

        % Menu selected function: HistogramAnalysisMenu
        function HistogramAnalysisMenuSelected(app, event)
            
        end

        % Menu selected function: PhasorDenoisingMenu
        function PhasorDenoisingMenuSelected(app, event)
            
        end

        % Menu selected function: IncludeAllSpectrainCompositeMenu
        function IncludeAllSpectrainCompositeMenuSelected(app, event)
            
        end

        % Menu selected function: IncludeSaturatedPixelsMenu
        function IncludeSaturatedPixelsMenuSelected(app, event)
            
        end

        % Menu selected function: GaussianBlurMenu
        function GaussianBlurMenuSelected(app, event)
            
        end

        % Menu selected function: ApplyFlatfieldCorrectionMenu
        function ApplyFlatfieldCorrectionMenuSelected(app, event)
            
        end

        % Menu selected function: GlobalROIMenu
        function GlobalROIMenuSelected(app, event)
            
        end

        % Menu selected function: ApplyFiberImageFilterMenu
        function ApplyFiberImageFilterMenuSelected(app, event)
            
        end

        % Menu selected function: SamplingCorrectionMenu
        function SamplingCorrectionMenuSelected(app, event)
            
        end

        % Menu selected function: SpectralSmoothingMenu
        function SpectralSmoothingMenuSelected(app, event)
            
        end

        % Menu selected function: hyperCFMEtestMenu
        function hyperCFMEtestMenuSelected(app, event)
            
        end

        % Menu selected function: MaestrotestMenu
        function MaestrotestMenuSelected(app, event)
            
        end

        % Menu selected function: ENVItestapplesMenu
        function ENVItestapplesMenuSelected(app, event)
            
        end

        % Menu selected function: ENVItestbloodstainsMenu
        function ENVItestbloodstainsMenuSelected(app, event)
            
        end

        % Menu selected function: OlympustestMenu
        function OlympustestMenuSelected(app, event)
            
        end

        % Menu selected function: OIRtiffstestMenu
        function OIRtiffstestMenuSelected(app, event)
            
        end

        % Menu selected function: DebugcodeMenu
        function DebugcodeMenuSelected(app, event)
            test = 1; %#ok<NASGU> 
        end

        % Cell edit callback: BSLTable
        function BSLTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % go and update the BSL
            
            
        end

        % Button pushed function: UnmixButton
        function UnmixButtonPushed(app, event)
            
        end

        % Button pushed function: SaveBSLButton
        function SaveBSLButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DirectoryLabel = uilabel(app.LeftPanel);
            app.DirectoryLabel.Text = 'Directory';
            app.DirectoryListBox = uilistbox(app.LeftPanel);
            app.ControlPanel = uipanel(app.LeftPanel);
            app.ControlPanel.Title = 'Control Panel';
            app.UnmixAllButton = uibutton(app.ControlPanel, 'push');
            app.UnmixAllButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixAllButtonPushed, true);
            app.UnmixAllButton.Text = 'Unmix All';
            app.CenterPanel = uipanel(app.GridLayout);
            app.HyperViewer2Label = uilabel(app.CenterPanel);
            app.HyperViewer2Label.Text = 'HyperViewer2';
            app.StatusLabel = uilabel(app.CenterPanel);
            app.StatusLabel.Text = 'Status: ';
            app.UIAxes1 = uiaxes(app.CenterPanel);
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.UIAxes2 = uiaxes(app.CenterPanel);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.BSLTable = uitable(app.RightPanel);
            app.BSLTable.ColumnName = {'A matrix?'; 'Dye'; 'Target'; 'Color'};
            app.BSLTable.RowName = {};
            app.BSLTable.CellEditCallback = createCallbackFcn(app, @BSLTableCellEdit, true);
            app.BSLLabel = uilabel(app.RightPanel);
            app.BSLLabel.Text = 'BSL';
            app.UnmixButton = uibutton(app.RightPanel, 'push');
            app.UnmixButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixButtonPushed, true);
            app.UnmixButton.Text = 'Unmix';
            app.SaveBSLButton = uibutton(app.RightPanel, 'push');
            app.SaveBSLButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBSLButtonPushed, true);
            app.SaveBSLButton.Text = 'Save BSL';
            app.SaveBSL2Button = uibutton(app.RightPanel, 'push');
            app.SaveBSL2Button.Text = 'Save BSL 2';
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.NewMenu = uimenu(app.File);
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.hyperCFMEMenu = uimenu(app.NewMenu);
            app.hyperCFMEMenu.MenuSelectedFcn = createCallbackFcn(app, @hyperCFMEMenuSelected, true);
            app.hyperCFMEMenu.Text = 'hyperCFME';
            app.MaestroMenu = uimenu(app.NewMenu);
            app.MaestroMenu.MenuSelectedFcn = createCallbackFcn(app, @MaestroMenuSelected, true);
            app.MaestroMenu.Text = 'Maestro';
            app.BioFormatsMenu = uimenu(app.NewMenu);
            app.BioFormatsMenu.MenuSelectedFcn = createCallbackFcn(app, @BioFormatsMenuSelected, true);
            app.BioFormatsMenu.Text = 'BioFormats';
            app.DavisLabMenu = uimenu(app.NewMenu);
            app.DavisLabMenu.MenuSelectedFcn = createCallbackFcn(app, @DavisLabMenuSelected, true);
            app.DavisLabMenu.Text = 'DavisLab';
            app.ENVIMenu = uimenu(app.NewMenu);
            app.ENVIMenu.MenuSelectedFcn = createCallbackFcn(app, @ENVIMenuSelected, true);
            app.ENVIMenu.Text = 'ENVI';
            app.OpenMenu = uimenu(app.File);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ReloadMenu = uimenu(app.File);
            app.ReloadMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloadMenuSelected, true);
            app.ReloadMenu.Text = 'Reload';
            app.SaveMenu = uimenu(app.File);
            app.SaveMenu.Text = 'Save';
            app.AnalysisMenu = uimenu(app.SaveMenu);
            app.AnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalysisMenuSelected, true);
            app.AnalysisMenu.Text = 'Analysis';
            app.AllAnalysesMenu = uimenu(app.SaveMenu);
            app.AllAnalysesMenu.MenuSelectedFcn = createCallbackFcn(app, @AllAnalysesMenuSelected, true);
            app.AllAnalysesMenu.Text = 'All Analyses';
            app.AsTiffStacksMenu = uimenu(app.SaveMenu);
            app.AsTiffStacksMenu.MenuSelectedFcn = createCallbackFcn(app, @AsTiffStacksMenuSelected, true);
            app.AsTiffStacksMenu.Text = 'As Tiff Stacks';
            app.NewImageMenu = uimenu(app.SaveMenu);
            app.NewImageMenu.MenuSelectedFcn = createCallbackFcn(app, @NewImageMenuSelected, true);
            app.NewImageMenu.Text = 'New Image';
            app.ExportAsMenu = uimenu(app.File);
            app.ExportAsMenu.Text = 'Export As';
            app.MovieMenu = uimenu(app.ExportAsMenu);
            app.MovieMenu.MenuSelectedFcn = createCallbackFcn(app, @MovieMenuSelected, true);
            app.MovieMenu.Text = 'Movie';
            app.NewBasisSpectraMenu = uimenu(app.ExportAsMenu);
            app.NewBasisSpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @NewBasisSpectraMenuSelected, true);
            app.NewBasisSpectraMenu.Text = 'New Basis Spectra';
            app.TilesMenu = uimenu(app.ExportAsMenu);
            app.TilesMenu.MenuSelectedFcn = createCallbackFcn(app, @TilesMenuSelected, true);
            app.TilesMenu.Text = 'Tiles';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.SpectraViewerMenu = uimenu(app.ToolsMenu);
            app.SpectraViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @SpectraViewerMenuSelected, true);
            app.SpectraViewerMenu.Text = 'SpectraViewer';
            app.SpeedtestCPUMenu = uimenu(app.ToolsMenu);
            app.SpeedtestCPUMenu.MenuSelectedFcn = createCallbackFcn(app, @SpeedtestCPUMenuSelected, true);
            app.SpeedtestCPUMenu.Text = 'Speedtest CPU';
            app.SpeedtestGPUMenu = uimenu(app.ToolsMenu);
            app.SpeedtestGPUMenu.MenuSelectedFcn = createCallbackFcn(app, @SpeedtestGPUMenuSelected, true);
            app.SpeedtestGPUMenu.Text = 'Speedtest GPU';
            app.HistogramAnalysisMenu = uimenu(app.ToolsMenu);
            app.HistogramAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramAnalysisMenuSelected, true);
            app.HistogramAnalysisMenu.Text = 'Histogram Analysis';
            app.ROIMenu = uimenu(app.ToolsMenu);
            app.ROIMenu.Text = 'ROI';
            app.NewROIMenu = uimenu(app.ROIMenu);
            app.NewROIMenu.MenuSelectedFcn = createCallbackFcn(app, @NewROIMenuSelected, true);
            app.NewROIMenu.Text = 'New ROI';
            app.AddROIMenu = uimenu(app.ROIMenu);
            app.AddROIMenu.MenuSelectedFcn = createCallbackFcn(app, @AddROIMenuSelected, true);
            app.AddROIMenu.Text = 'Add ROI';
            app.NewPointMenu = uimenu(app.ROIMenu);
            app.NewPointMenu.MenuSelectedFcn = createCallbackFcn(app, @NewPointMenuSelected, true);
            app.NewPointMenu.Text = 'New Point';
            app.AddPointMenu = uimenu(app.ROIMenu);
            app.AddPointMenu.MenuSelectedFcn = createCallbackFcn(app, @AddPointMenuSelected, true);
            app.AddPointMenu.Text = 'Add Point';
            app.AddROItoBSLMenu = uimenu(app.ROIMenu);
            app.AddROItoBSLMenu.MenuSelectedFcn = createCallbackFcn(app, @AddROItoBSLMenuSelected, true);
            app.AddROItoBSLMenu.Text = 'Add ROI to BSL';
            app.ClearLastROIMenu = uimenu(app.ROIMenu);
            app.ClearLastROIMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearLastROIMenuSelected, true);
            app.ClearLastROIMenu.Text = 'Clear Last ROI';
            app.AverageExptMenu = uimenu(app.ToolsMenu);
            app.AverageExptMenu.Text = 'Average Expt';
            app.PreunmixingMenu = uimenu(app.AverageExptMenu);
            app.PreunmixingMenu.MenuSelectedFcn = createCallbackFcn(app, @PreunmixingMenuSelected, true);
            app.PreunmixingMenu.Text = 'Pre-unmixing';
            app.PostunmixingMenu = uimenu(app.AverageExptMenu);
            app.PostunmixingMenu.MenuSelectedFcn = createCallbackFcn(app, @PostunmixingMenuSelected, true);
            app.PostunmixingMenu.Text = 'Post-unmixing';
            app.PhasorDenoisingMenu = uimenu(app.ToolsMenu);
            app.PhasorDenoisingMenu.MenuSelectedFcn = createCallbackFcn(app, @PhasorDenoisingMenuSelected, true);
            app.PhasorDenoisingMenu.Text = 'Phasor Denoising';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.IncludeAllSpectrainCompositeMenu = uimenu(app.OptionsMenu);
            app.IncludeAllSpectrainCompositeMenu.MenuSelectedFcn = createCallbackFcn(app, @IncludeAllSpectrainCompositeMenuSelected, true);
            app.IncludeAllSpectrainCompositeMenu.Text = 'Include All Spectra in Composite';
            app.IncludeSaturatedPixelsMenu = uimenu(app.OptionsMenu);
            app.IncludeSaturatedPixelsMenu.MenuSelectedFcn = createCallbackFcn(app, @IncludeSaturatedPixelsMenuSelected, true);
            app.IncludeSaturatedPixelsMenu.Text = 'Include Saturated Pixels';
            app.GaussianBlurMenu = uimenu(app.OptionsMenu);
            app.GaussianBlurMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianBlurMenuSelected, true);
            app.GaussianBlurMenu.Text = 'Gaussian Blur';
            app.ApplyFlatfieldCorrectionMenu = uimenu(app.OptionsMenu);
            app.ApplyFlatfieldCorrectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyFlatfieldCorrectionMenuSelected, true);
            app.ApplyFlatfieldCorrectionMenu.Text = 'Apply Flatfield Correction';
            app.GlobalROIMenu = uimenu(app.OptionsMenu);
            app.GlobalROIMenu.MenuSelectedFcn = createCallbackFcn(app, @GlobalROIMenuSelected, true);
            app.GlobalROIMenu.Text = 'Global ROI';
            app.ApplyFiberImageFilterMenu = uimenu(app.OptionsMenu);
            app.ApplyFiberImageFilterMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyFiberImageFilterMenuSelected, true);
            app.ApplyFiberImageFilterMenu.Separator = 'on';
            app.ApplyFiberImageFilterMenu.Text = 'Apply Fiber Image Filter';
            app.SamplingCorrectionMenu = uimenu(app.OptionsMenu);
            app.SamplingCorrectionMenu.MenuSelectedFcn = createCallbackFcn(app, @SamplingCorrectionMenuSelected, true);
            app.SamplingCorrectionMenu.Text = 'Sampling Correction';
            app.SpectralSmoothingMenu = uimenu(app.OptionsMenu);
            app.SpectralSmoothingMenu.MenuSelectedFcn = createCallbackFcn(app, @SpectralSmoothingMenuSelected, true);
            app.SpectralSmoothingMenu.Text = 'Spectral Smoothing';
            app.DemoMenu = uimenu(app.UIFigure);
            app.DemoMenu.Text = 'Demo';
            app.hyperCFMEtestMenu = uimenu(app.DemoMenu);
            app.hyperCFMEtestMenu.MenuSelectedFcn = createCallbackFcn(app, @hyperCFMEtestMenuSelected, true);
            app.hyperCFMEtestMenu.Text = 'hyperCFME test';
            app.MaestrotestMenu = uimenu(app.DemoMenu);
            app.MaestrotestMenu.MenuSelectedFcn = createCallbackFcn(app, @MaestrotestMenuSelected, true);
            app.MaestrotestMenu.Text = 'Maestro test';
            app.ENVItestapplesMenu = uimenu(app.DemoMenu);
            app.ENVItestapplesMenu.MenuSelectedFcn = createCallbackFcn(app, @ENVItestapplesMenuSelected, true);
            app.ENVItestapplesMenu.Text = 'ENVI test - apples';
            app.ENVItestbloodstainsMenu = uimenu(app.DemoMenu);
            app.ENVItestbloodstainsMenu.MenuSelectedFcn = createCallbackFcn(app, @ENVItestbloodstainsMenuSelected, true);
            app.ENVItestbloodstainsMenu.Text = 'ENVI test - bloodstains';
            app.OlympustestMenu = uimenu(app.DemoMenu);
            app.OlympustestMenu.MenuSelectedFcn = createCallbackFcn(app, @OlympustestMenuSelected, true);
            app.OlympustestMenu.Text = 'Olympus test';
            app.OIRtiffstestMenu = uimenu(app.DemoMenu);
            app.OIRtiffstestMenu.MenuSelectedFcn = createCallbackFcn(app, @OIRtiffstestMenuSelected, true);
            app.OIRtiffstestMenu.Text = 'OIR-tiffs test';
            app.DebugcodeMenu = uimenu(app.DemoMenu);
            app.DebugcodeMenu.MenuSelectedFcn = createCallbackFcn(app, @DebugcodeMenuSelected, true);
            app.DebugcodeMenu.Text = 'Debug code';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hyperviewer2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end